% description: V1.06 generated July 7th 2021
% - P filter based on "Fast extraction of neuron morphologies from large-scale SBFSEM image stacks" (Lang 2011 J comput neurosci).
% - Uses the TREES toolbox (Copyright (C) 2009 - 2018  Hermann Cuntz).
% - Matlab app requires Matlab Image Processing Toolbox.
classdef arborTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArborTools                      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ModeTabGroup                    matlab.ui.container.TabGroup
        ExtractionmodeTab               matlab.ui.container.Tab
        CropModeButton                  matlab.ui.control.StateButton
        segSkelTabGroup                 matlab.ui.container.TabGroup
        segCtrlsTab                     matlab.ui.container.Tab
        segCtrlsPanel                   matlab.ui.container.Panel
        PrescaleSpinner                 matlab.ui.control.Spinner
        PrescaleSpinnerLabel            matlab.ui.control.Label
        ClosingelementsizeSpinner       matlab.ui.control.Spinner
        ClosingelementsizeSpinnerLabel  matlab.ui.control.Label
        ApplyPFilterButton              matlab.ui.control.Button
        GEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        DandELabel                      matlab.ui.control.Label
        DeltaEpsiEditField              matlab.ui.control.NumericEditField
        cSpinner                        matlab.ui.control.Spinner
        cSpinnerLabel                   matlab.ui.control.Label
        aandbSpinner                    matlab.ui.control.Spinner
        aandbSpinnerLabel               matlab.ui.control.Label
        SkeletonizationTab              matlab.ui.container.Tab
        ErodevolumeCheckBox             matlab.ui.control.CheckBox
        EndpointcandidatesEditField     matlab.ui.control.NumericEditField
        EndpointcandidatesEditFieldLabel  matlab.ui.control.Label
        BranchcandidatesEditField       matlab.ui.control.NumericEditField
        BranchcandidatesEditFieldLabel  matlab.ui.control.Label
        MinimumBranchLengthEditField    matlab.ui.control.NumericEditField
        MinimumBranchLengthEditFieldLabel  matlab.ui.control.Label
        SkeletonizeButton               matlab.ui.control.Button
        SmoothingSizeSpinner            matlab.ui.control.Spinner
        SmoothingSizeSpinnerLabel       matlab.ui.control.Label
        WritedatafileButton             matlab.ui.control.Button
        VolumeremovalPanel              matlab.ui.container.Panel
        RemoveVolsButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SelectcropwindowLamp            matlab.ui.control.Lamp
        CropstackButton                 matlab.ui.control.Button
        SelectfileDropDown              matlab.ui.control.DropDown
        SelectfileDropDownLabel         matlab.ui.control.Label
        XYsizemicronsEditField          matlab.ui.control.NumericEditField
        XYsizemicronsEditFieldLabel     matlab.ui.control.Label
        SelectfolderLamp                matlab.ui.control.Lamp
        SelectfolderButton              matlab.ui.control.Button
        BatchanalysisTab                matlab.ui.container.Tab
        CreateSWCfilesCheckBox          matlab.ui.control.CheckBox
        SelectOutputFolderLamp          matlab.ui.control.Lamp
        SelectOutputFolderButton        matlab.ui.control.Button
        CreateProjectionsCheckbox       matlab.ui.control.CheckBox
        ProjectionControlsPanel         matlab.ui.container.Panel
        PickBSkelLamp                   matlab.ui.control.Lamp
        PickBSkelButton                 matlab.ui.control.Button
        belowSkelHullInclude            matlab.ui.control.CheckBox
        PickTSkelLamp                   matlab.ui.control.Lamp
        PickTSkelButton                 matlab.ui.control.Button
        totalSkelHullInclude            matlab.ui.control.CheckBox
        PickASkelLamp                   matlab.ui.control.Lamp
        PickASkelButton                 matlab.ui.control.Button
        aboveSkelHullInclude            matlab.ui.control.CheckBox
        PickSkeletonLamp                matlab.ui.control.Lamp
        PickSkeletonButton              matlab.ui.control.Button
        DrawskeletonCheckBox            matlab.ui.control.CheckBox
        PickBelowLineLamp               matlab.ui.control.Lamp
        PickBelowLineButton             matlab.ui.control.Button
        tbToBelowInclude                matlab.ui.control.CheckBox
        PickAboveLineButton             matlab.ui.control.Button
        PickAboveLineLamp               matlab.ui.control.Lamp
        tbToAboveInclude                matlab.ui.control.CheckBox
        PickTotalColorButton            matlab.ui.control.Button
        PickTotalColorLamp              matlab.ui.control.Lamp
        PickBelowColorButton            matlab.ui.control.Button
        PickBelowColorLamp              matlab.ui.control.Lamp
        PickAboveColorLamp              matlab.ui.control.Lamp
        PickAboveColorButton            matlab.ui.control.Button
        AllHullInclude                  matlab.ui.control.CheckBox
        BelowHullInclude                matlab.ui.control.CheckBox
        AboveHullInclude                matlab.ui.control.CheckBox
        StartProcessingButton           matlab.ui.control.Button
        TBZxlsxLamp                     matlab.ui.control.Lamp
        TBZxlsxButton                   matlab.ui.control.Button
        ProcessingcontrolsPanel         matlab.ui.container.Panel
        ZScaleEditField                 matlab.ui.control.NumericEditField
        ZScaleEditFieldLabel            matlab.ui.control.Label
        ZlevelcutoffEditField           matlab.ui.control.NumericEditField
        ZlevelcutoffEditFieldLabel      matlab.ui.control.Label
        SelectmatFolderLamp             matlab.ui.control.Lamp
        SelectmatFolderButton           matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %{
            - E_ variables are only used in extraction mode
            - A_ variables are only used in analysis mode
        %}
        E_scale          % Scale used to increase number of voxels to work on. Integer multiples of 2 (0, 2, 4, etc). Used in P filtering.
        E_minBranchLen   % Used when applying bwskel(). Stored as microns
        E_SE3DSize       % Used when smoothing with imclose(). Stored as pixels.
        E_XYSizeMicrons  % Used to get XY scale factor (assumes one micron per slice for Z)
        E_XYPixPerMicron % Set when file dropdown changed
        E_cropCuboid     % Set when user finishes imcrop operation triggered by crop button press
        E_TIFFolderPath  % Set from output of user prompt when select folder button pushed. Used to get E_files using app.getFiles_F
        E_files          % Set when user presses select folder button (output of app.getFiles_F)
        E_currTIF        % Currently selected TIF (full path)
        E_origStack      % Stack loaded from file and inverted (Z). No filters applied yet
        E_cropStack      % E_origStack with user chosen crop window applied
        E_polyStack      % E_origStack where everything outside 3D polygon set to 0 (polygonal cropping mode)
        E_polyMask3D     % 3D polygon creating by and-ing each of the three 2D projections together (logical array same size as origStack).
        E_ZProjection    % Z projection of origStack. Used to assist cropping
        E_PFiltered
        E_PLabeled       % E_PFiltered only keeping 20 largest volumes and then converted to label array with bwlabeln()
        E_altLabel       % E_PLabeled after selected volumes have been removed (set = 0). Written to file, not used in labelvolshow()
        E_IDsRemoved     % Tracks labels removed from E_PLabeled to make E_altLabel
        E_labelData      % Store data on labels (ID, volume, color, etc.)
        E_skelVol        % Skeleton of E_altLabel (logical volume)
        E_skelEndPts     % Candidate end points (logical volume)
        E_skelBPts       % Candidate branch points (logical volume)
        
        A_matFolderPath 
        A_files 
        A_TBZ            % Row vector of taste but Z levels for each file
        A_outputFolder
    end
    
    methods (Access = private)
        
        function [PFiltered, PScore] = langP_F(app, topFiltered, scale)
            %{
            % langP_F
            %
            % PURPOSE: Implementation of P function from Lang et al.
            % (2011 J comput neurosci)
            %
            % INPUTS:
            %   - topFiltered = image stack after crop and applying their top hat filter
            %   - scale = Resize factor used to increase ability to detect thin structures. Multiple of 2
            %   
            % OUTPUTS:
            %   - PFiltered = Logical/binary image stack after P filter applied plus
            %     fill/clean operations
            %   - Pscore = Score to indicate the number of voxels set to 1 by P
            %     function = 10,000*(numVoxPSetTo1/totalNumVoxelsInStack)
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2019b but may work
            % 	on earlier versions). Also:
            %   - Matlab image processing toolbox (only for multithresh and bwmorph3)
            %   - getN26_F
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 2020.
            %
            % NOTES:
            %   - Paper = "Fast extraction of neuron
            %       morphologies from large-scale SBFSEM image stacks" (Lang 2011)
            %}
            a = app.aandbSpinner.Value;
            b = a;
            c = app.cSpinner.Value;
            delta = app.DeltaEpsiEditField.Value;
            gamma = app.GEditField.Value;
            epsilon = app.DeltaEpsiEditField.Value;
            if scale > 0; topFiltered = imresize3(topFiltered, scale); end
            [threeRegT, ~] = multithresh(topFiltered, 2); % Thresh for three regions using otsu method
            ThMin = threeRegT(1);
            ThMax = threeRegT(2);
            PFiltered = zeros(size(topFiltered));
            Xlim = size(topFiltered, 1);
            Ylim = size(topFiltered, 2);
            Zlim = size(topFiltered, 3);
            numVox = numel(topFiltered);
            N18(:, :, 1) = [-1 1 -1; 1 1 1; -1 1 -1]; % This array multiplied by N26 to get a usable N18
            N18(:, :, 2) = [1 1 1; 1 -1 1; 1 1 1];
            N18(:, :, 3) = [-1 1 -1; 1 1 1; -1 1 -1];
            Pcount = 0;
            for Z = 1:size(topFiltered, 3)
                for col = 1:size(topFiltered, 2)
                    for row = 1:size(topFiltered, 1)
                        currVox = topFiltered(row, col, Z);
                        if currVox > ThMax
                            PFiltered(row, col, Z) = 1;
                            continue
                        end
                        if currVox < ThMin
                            PFiltered(row, col, Z) = 0;
                            continue
                        end
                        Xmin = nearest(row - (a/2));
                        if Xmin <= 0; Xmin = 1; end
                        Xmax = nearest(row + (a/2));
                        if Xmax > Xlim; Xmax = Xlim; end
                        Ymin = nearest(col - (b/2));
                        if Ymin <= 0; Ymin = 1; end
                        Ymax = nearest(col + (b/2));
                        if Ymax > Ylim; Ymax = Ylim; end
                        Zmin = nearest(Z - (c/2));
                        if Zmin <= 0; Zmin = 1; end
                        Zmax = nearest(Z + (c/2));
                        if Zmax > Zlim; Zmax = Zlim; end
                        MNeighborhood = topFiltered(Xmin:Xmax, Ymin:Ymax, Zmin:Zmax);
                        M = mean2(MNeighborhood);
                        Mepsi = M + epsilon; % Used in finding Acount
                        N26 = app.getN26_F(topFiltered, row, col, Z); % Is N27, includes center voxel
                        if N26 ~= 0
                            altN18 = N18.*N26; % Corners become negative, use to remove using find > 0
                            Acount = numel(find(altN18 > Mepsi));
                        else
                            Acount = 0;
                        end
                        T1 = 0;  % Term 1 (see p(x, y, z) in Lang et al pg 537)
                        T2 = 0;
                        if M > (ThMin + delta); T1 = 1; end
                        if (Acount/18) > gamma; T2 = 1; end
                        P = T1 & T2; % P function is a logical AND of T1 and T2
                        if P
                            PFiltered(row, col, Z) = 1;
                            Pcount = Pcount+1;
                        else
                            PFiltered(row, col, Z) = 0;
                        end
                    end
                end
            end
            PFiltered = bwmorph3(bwmorph3(logical(PFiltered), 'fill'), 'clean');
            if scale > 0
                PFiltered = imbinarize(imresize3(double(PFiltered), 1/scale));
            end
            PScore = 10000*(Pcount/numVox);
        end
        
        function N26 = getN26_F(~, volume, X, Y, Z)
            %{
            % getN26_F
            %
            % PURPOSE: Return the N26 neighborhood for a given XYZ pixel
            %
            % INPUTS:
            %   - Main volume 'volume'
            %   - X, Y and Z center of N26 (must be integers)
            %
            % OUTPUTS:
            %   - Neighborgood volume (3D matrix)
            %   --- Includes center XYZ voxel value, you need to handle this outside function (done for func speed)
            %       ----- So is N27 technically since it includes center voxel
            %   --- Returns N26 = 0 if not enough voxels to fill N26 (i.e at volume edge)
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2019b but may work
            % 	on earlier versions).
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 2020.
            %
            % NOTES:
            %}
            Xlim = size(volume, 1);
            Ylim = size(volume, 2);
            Zlim = size(volume, 3);
            Xmin = X-1;
            if Xmin <= 0; N26 = 0; return; end
            Xmax = X+1;
            if Xmax > Xlim; N26 = 0; return; end
            Ymin = Y-1;
            if Ymin <= 0; N26 = 0; return; end
            Ymax = Y+1;
            if Ymax > Ylim; N26 = 0; return; end
            Zmin = Z-1;
            if Zmin <= 0; N26 = 0; return; end
            Zmax = Z+1;
            if Zmax > Zlim; N26 = 0; return; end
            N26 = volume(Xmin:Xmax, Ymin:Ymax, Zmin:Zmax);
        end
        
        function [imStack] = readTIFFStack_F(~, fileLocation)
            %{
            % readTIFFStack_F
            %
            % PURPOSE: Read a TIFF image as an numRows by numCols by numZ array
            %
            % INPUTS:
            %   - folderPath = Full path to the tiff image ('C:\.....\myImage.tif')
            %
            % OUTPUTS:
            %   - imStack = numRows by numCols by numInStack array
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2019b but may work
            % 	on earlier versions). Also Image processing toolbox.
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 2020.
            %
            % NOTES:
            %   - Assumes all images in stack have same zoom level and XY dimensions
            %
            %   - There is now a built in Matlab function that does this,
            %   use it rather than this for any new programs: 
            %   (tiffreadVolume - added in R2020b)
            %}
            stackInfo = imfinfo(fileLocation);
            numInStack = numel(stackInfo);
            width = stackInfo(1).Width;
            height = stackInfo(1).Height;
            imStack = zeros(width, height, numInStack);
            for n = 1:numInStack
                currFrame = imread(fileLocation, n, 'Info', stackInfo);
                imStack(:, :, n) = currFrame;
            end
        end
        
        function [files, numFiles] = getFiles_F(~, folderPath, extFilt)
            %{
            % getFiles_F
            %
            % PURPOSE: Get file names and paths as two columns of cells.
            %
            % INPUTS:
            %   - folderPath = Char path to the folder containing files.
            %   - extilter = Extension filter of files you want to find in folderPath.
            %       -- '.jpg' or '.tif' for example
            %       -- '0' for no fiter
            %
            % OUTPUTS:
            %   - files = 2xN cell of N filepaths and file names
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2019b but may work
            % 	on earlier versions).
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 2020.
            %
            % NOTES:
            %}
            currDir = dir(folderPath);
            currDir(1:2, :) = []; % Remove . and ..
            files{:} = '';
            n = 0;
            indx = 0;
            if ~strcmp(extFilt, '0')
                for i = 1:1:numel(currDir)
                    currFile = currDir(i).name;
                    if ~endsWith(currFile, extFilt, 'IgnoreCase', true)
                        n = n+1;
                        indx(n, 1) = i; %#ok<AGROW>
                    end
                end
                if ~(indx == 0); currDir(indx) = []; end
                if numel(currDir) == 0
                    numFiles = 0;
                    return
                end
            end
            numFiles = numel(currDir);
            for n = 1:1:numFiles
                files{n,1} = strcat(currDir(n).folder, '\', currDir(n).name);
                files{n,2} = currDir(n).name;
            end
        end
        
        function [P1, P2, P3] = genProjections_F(~, imStack, S)
            %{
            % genProjections_F
            %
            % PURPOSE: Generate three 2D projections of a stack (enhanced
            %   by fibermetric filter).
            %
            % INPUTS:
            %   - imStack = 3D array of image stack (any type)
            %   - S = A structure sensitivity used with fibermetric(). 
            %       3 is a good starting point.
            %
            % OUTPUTS:
            %   - P1, P2, and P3 = 2D projections
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2020b but may work
            % 	on earlier versions). Also Image processing toolbox.
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 4-2021.
            %
            % NOTES:
            %  - Small helper function to keep track of this method
            %
            %  - P3 is the top down projection
            %}
            fiber = fibermetric(imStack, 'StructureSensitivity', S);
            P1 = squeeze(max(fiber, [], 1));
            P2 = squeeze(max(fiber, [], 2));
            P3 = squeeze(max(fiber, [], 3));
        end
        
        function updateAxes_2D(app, image)
            % Used when want to draw 2D image to the main UIAxes
            I = imshow(image, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end
                        
        function resetPFiltControls_F(app) % Reset all the controllable P filter values to their default values
            app.aandbSpinner.Value = 5;
            app.cSpinner.Value = 3;
            app.ClosingelementsizeSpinner.Value = 0;
            app.PrescaleSpinner.Value = 0;
        end
        
        function [x, y] = bresenham_F(~, x1, y1, x2, y2)
            %{
            Copyright (c) 2010, Aaron  Wetzler
            All rights reserved.
            
            Redistribution and use in source and binary forms, with or without
            modification, are permitted provided that the following conditions are
            met:
            
                * Redistributions of source code must retain the above copyright
                  notice, this list of conditions and the following disclaimer.
                * Redistributions in binary form must reproduce the above copyright
                  notice, this list of conditions and the following disclaimer in
                  the documentation and/or other materials provided with the distribution
            
            THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS"
            AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE
            IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE
            ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT OWNER OR CONTRIBUTORS BE
            LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR
            CONSEQUENTIAL DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF
            SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS
            INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER IN
            CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE)
            ARISING IN ANY WAY OUT OF THE USE OF THIS SOFTWARE, EVEN IF ADVISED OF THE
            POSSIBILITY OF SUCH DAMAGE.
            %}
            %{
            %Matlab optmized version of Bresenham line algorithm. No loops.
            %Slightly altered by David Alston 10-2018
            %Format:
            %               [x y]=bham(x1,y1,x2,y2)
            %
            %Input:
            %               (x1,y1): Start position
            %               (x2,y2): End position
            %
            %Output:
            %               x y: the line coordinates from (x1,y1) to (x2,y2)
            %
            %Usage example:
            %               [x y]=bham(1,1, 10,-5);
            %               plot(x,y,'or');
            %}
            x1=round(x1); x2=round(x2);
            y1=round(y1); y2=round(y2);
            dx=abs(x2-x1);
            dy=abs(y2-y1);
            steep=abs(dy)>abs(dx);
            if steep t=dx;dx=dy;dy=t; end %#ok<SEPEX> 
            
            %The main algorithm goes here.
            if dy==0
                q=zeros(dx+1,1);
            else
                q=[0;diff(mod([floor(dx/2):-dy:-dy*dx+floor(dx/2)]',dx))>=0]; %#ok<NBRAK> 
            end
            
            %and ends here.
            
            if steep
                if y1<=y2 y=[y1:y2]'; else y=[y1:-1:y2]'; end %#ok<NBRAK,SEPEX> 
                if x1<=x2 x=x1+cumsum(q);else x=x1-cumsum(q); end %#ok<SEPEX> 
            else
                if x1<=x2 x=[x1:x2]'; else x=[x1:-1:x2]'; end %#ok<NBRAK,SEPEX> 
                if y1<=y2 y=y1+cumsum(q);else y=y1-cumsum(q); end %#ok<SEPEX> 
            end
        end
        
        function convHullBWMask = BWVol2CHullMask_F(~, BW)
            %{
            % BWVol2CHullMask_F
            %
            % PURPOSE: Find the convex hull of a binary volume and return it as a
            % logical volume the same size as input BW.
            %
            % INPUTS:
            %   - BW = 3D logical array with at least four voxels set = true.
            %
            % OUTPUTS:
            %   - convHullBWMask = 3D logical array the same size as input BW
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2020b but may work
            % 	on earlier versions). No toolboxes should be required.
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 04-2021.
            %
            % NOTES:
            %  - This is a bit slow, I think there is better/faster way to do
            %    this but this works for now
            %
            %   - Must have at least four points in volume for this to work
            %       (three would just be a plane, etc).
            %}
            [XYZ(:, 1), XYZ(:, 2), XYZ(:, 3)] = ind2sub(size(BW), find(BW));
            minX = min(XYZ(:, 1)); % Used to reduce search area for function speed
            minY = min(XYZ(:, 2));
            minZ = min(XYZ(:, 3));
            maxX = max(XYZ(:, 1));
            maxY = max(XYZ(:, 2));
            maxZ = max(XYZ(:, 3));
            testArr = false(size(BW));
            testArr(minX:maxX, minY:maxY, minZ:maxZ) = true;
            [eXYZ(:, 1), eXYZ(:, 2), eXYZ(:, 3)] = ind2sub(size(BW), find(testArr)); % [row, col, z]
            alphaS = alphaShape(XYZ, Inf);      % Now contains alpha shape of convex hull
            temp = eXYZ.*inShape(alphaS, eXYZ); % As eXYZ but with XYZ coordinates outside the hull set to [0 0 0]
            hullPts = temp(any(temp, 2), :);    % Remove all [0 0 0] coordinates
            idx = sub2ind(size(BW), hullPts(:, 1), hullPts(:, 2), hullPts(:, 3)); % Indices of conv hull coordinates
            convHullBWMask = false(size(BW));
            convHullBWMask(idx) = true;
        end
        
        function [projData, data, errFlag] = genArborData_F(app, BW, skel, origStack, XYpixPerMicron, ZScale, TBZlevel, options)
            %{
            % genArborData_F
            %
            % PURPOSE: Generate data about stack, get trees structures, and
            %   create 2D projections with overlays (if requested).
            %
            % INPUTS:
            %   - BW = 3D logical array of the arbor
            %   - skel = 3D logical array of arbor skeleton (same size as BW)
            %   - origStack - Original 3D array of arbor (double etc)
            %   - XYpixPerMicron - From extraction mode. Used with treestoolbox to get skeleton lengths
            %   - ZScale - How much to scale the final image/data by (ex by 6.4 to get 1-1-1 XYZ scaling)
            %   - TBZLevel - Level of the tastebud in Z (positive integer) as read from excel
            %   - options - Options/flags struct used to create final projection image (which hulls, include centroid lines, etc)
            %
            % OUTPUTS:
            %   - projData - Struct containing the projections with colored overlays burned in plus the
            %     centroid lines drawn
            %   - data - Gathered during processing (convex hulls, centroids, etc).
            %   - errFlag - See PDF manual.
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2019b but may work
            % 	on earlier versions). Image processing toolbox plus the following:
            %       - bresenham_F
            %       - BWVol2CHullMask_F
            %       - genScaledTrees_F (and the Treestoolbox)
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 07-2021.
            %
            % NOTES:
            %}
            origBW = BW;
            origSkel = skel;
            BW = flip(BW, 1); % Flip is optional, used to match orientation of existing figures
            skel = flip(skel, 1);
            zLevel = size(BW, 3) - TBZlevel;
            data.modZLevFlag = 0;
            if zLevel <= 2 % Need at least 3 layers above/below Z level for this method
                zLevel = 3;
                data.modZLevFlag = 1; 
            elseif zLevel >= size(BW, 3)
                zLevel = size(BW, 3)-2;
                data.modZLevFlag = 1;
            end
            [tTree, aTree, bTree, tLen, aLen, bLen] = app.genScaledTrees_F(skel, XYpixPerMicron, ZScale, zLevel);
            zPercent = zLevel/size(BW, 3); % Used in scaling later
            TBSlice = BW(:, :, zLevel);    % Chosen z level slice used to get centroid to measure against      
            if sum(sum(TBSlice)) == 0      % No pixels set true in tastebud Z level. Modify TB slice for centroid and continue
                [~, ~, Z] = ind2sub(size(BW), find(BW));
                minZDiff = abs(min(Z)-zLevel);
                maxZDiff = abs(max(Z)-zLevel);
                if minZDiff < maxZDiff
                    TBSlice = BW(:, :, min(Z));
                else
                    TBSlice = BW(:, :, max(Z));
                end
            end
            BW = logical(imresize3(double(BW), 'Scale', [1 1 ZScale], 'method', 'cubic'));       % 1 of 2 volume resize operations in function
            skel = logical(imresize3(double(skel), 'Scale', [1 1 ZScale], 'method', 'nearest')); % Other resize methods produce erronous data for a skeleton
            scaledZ = round(zPercent*size(BW, 3));
            aboveBW = BW;
            belowBW = BW;
            aboveSkel = skel;
            belowSkel = skel;
            aboveBW(:, :, 1:scaledZ-1) = false; % -1 to include slice at chosen Z level
            belowBW(:, :, scaledZ:end) = false; % scaledZ:end = false removes above voxels
            aboveSkel(:, :, 1:scaledZ-1) = false;
            belowSkel(:, :, scaledZ:end) = false;
            errFlag = app.checkForErrFlags_F(BW, aboveBW, belowBW, skel, aboveSkel, belowSkel);            
            T1 = ~all(size(origStack) == size(origBW));
            T2 = ~all(size(origStack) == size(origSkel));
            if T1 || T2; errFlag = 7; end
            switch errFlag % Handle errFlags that need data modifications
                case 2 % Not enough points in above volume for convex hull or coplanar. Identical to errFlag 5.
                    aboveBW(1:10, 1:2:10, scaledZ:end) = true;
                    aboveSkel(1:10, 1:2:10, scaledZ:end) = true;
                case 3 % Not enough points in below volume for convex hull or coplanar. Identical to errFlag 6.
                    belowBW(1:10, 1:2:10, 1:scaledZ-1) = true; % Set a small portion to true for chull stuff
                    belowSkel(1:10, 1:2:10, 1:scaledZ-1) = true;
                case 5 % Not enough points in above skeleton for convex hull or coplanar. Identical to errFlag 2.
                    aboveBW(1:10, 1:2:10, scaledZ:end) = true;
                    aboveSkel(1:10, 1:2:10, scaledZ:end) = true;
                case 6 % Not enough points in below skeleton for convex hull or coplanar. Identical to errFlag 3.
                    belowBW(1:10, 1:2:10, 1:scaledZ-1) = true;
                    belowSkel(1:10, 1:2:10, 1:scaledZ-1) = true;
                otherwise
                    % Just do nothing if unhandled; only care about flags
                    % that need data manipulation
            end
            if (errFlag == 1) || (errFlag == 4) || (errFlag == 7)
                % Flags 1, 4 and 7 can't continue (no partial data to
                % write). So just exit out with data = 0 and allow loop to
                % continue to next .mat data
                projData = 0;
                data.TBZlevel = TBZlevel;
                data.matlabTBZlevel = zLevel;
                data.totalExtentX = 0;
                data.totalExtentY = 0;
                data.totalExtentZ = 0;
                data.aboveExtentX = 0;
                data.aboveExtentY = 0;
                data.aboveExtentZ = 0;
                data.belowExtentX = 0;
                data.belowExtentY = 0;
                data.belowExtentZ = 0;
                data.totalConvexHullVol = 0;
                data.aboveConvexHullVol = 0;
                data.belowConvexHullVol = 0;
                data.totalSkelConvexHullVol = 0;
                data.aboveSkelConvexHullVol = 0;
                data.belowSkelConvexHullVol = 0;
                data.totalTree = 0;
                data.aboveTree = 0;
                data.belowTree = 0;
                data.totalSkelLen = 0;
                data.aboveSkelLen = 0;
                data.belowSkelLen = 0;
                data.totalArborVolume = 0;
                data.aboveArborVolume = 0;
                data.belowArborVolume = 0;
                data.totalCentroidX = 0;
                data.totalCentroidY = 0;
                data.totalCentroidZ = 0;
                data.aboveCentroidX = 0;
                data.aboveCentroidY = 0;
                data.aboveCentroidZ = 0;
                data.belowCentroidX = 0;
                data.belowCentroidY = 0;
                data.belowCentroidZ = 0;
                data.TBCentroidX = 0;
                data.TBCentroidY = 0;
                data.TBCentroidZ = 0;
                data.aboveSkelLen = 0;
                data.belowSkelLen = 0;
                data.totalSkelLen = 0;
                data.aVsTBCentroidDistance = 0;
                data.bVsTBCentroidDistance = 0;
                return
            end
            [RCP(:, 1), RCP(:, 2), RCP(:, 3)] = ind2sub(size(BW), find(BW));
            [aRCP(:, 1), aRCP(:, 2), aRCP(:, 3)] = ind2sub(size(aboveBW), find(aboveBW));
            [bRCP(:, 1), bRCP(:, 2), bRCP(:, 3)] = ind2sub(size(belowBW), find(belowBW));
                        
            RCPextent = max(RCP) - min(RCP); 
            aRCPextent = max(aRCP) - min(aRCP);
            bRCPextent = max(bRCP) - min(bRCP);            

            totalConv = app.BWVol2CHullMask_F(BW);
            aboveConv = app.BWVol2CHullMask_F(aboveBW);
            belowConv = app.BWVol2CHullMask_F(belowBW);
            totalSkelConv = app.BWVol2CHullMask_F(skel);
            aboveSkelConv = app.BWVol2CHullMask_F(aboveSkel);
            belowSkelConv = app.BWVol2CHullMask_F(belowSkel);
            
            XYZextent = [RCPextent(2) RCPextent(1), RCPextent(3)]; % Row, col, plane -> XYZ
            aXYZextent = [aRCPextent(2) aRCPextent(1), aRCPextent(3)];
            bXYZextent = [bRCPextent(2) bRCPextent(1), bRCPextent(3)];
            
            [zXY(:, 1), zXY(:, 2)] = ind2sub(size(TBSlice), find(TBSlice)); % All XY locations == 1 in TBSlice
            TBCentroidRCP = [mean(zXY(:, 1)) mean(zXY(:, 2)), scaledZ];     % Centroid of where TB enters
            totalCentroidRCP = [mean(RCP(:, 1)) mean(RCP(:, 2)) mean(RCP(:, 3))];
            aCentroidRCP = [mean(aRCP(:, 1)) mean(aRCP(:, 2)) mean(aRCP(:, 3))];
            bCentroidRCP = [mean(bRCP(:, 1)) mean(bRCP(:, 2)) mean(bRCP(:, 3))];
            
            TBCentroid = [TBCentroidRCP(2), TBCentroidRCP(1), TBCentroidRCP(3)]; % Row, col, plane -> XYZ
            totalCentroid = [totalCentroidRCP(2), totalCentroidRCP(1), totalCentroidRCP(3)];
            aCentroid = [aCentroidRCP(2), aCentroidRCP(1), aCentroidRCP(3)];
            bCentroid = [bCentroidRCP(2), bCentroidRCP(1), bCentroidRCP(3)];                     
                                               
            totalProps = sortrows(regionprops3(totalConv), 1, 'descend'); % Edge cases can create two hulls. Sort by descending volume so can pick largest volume
            aboveProps = sortrows(regionprops3(aboveConv), 1, 'descend');
            belowProps = sortrows(regionprops3(belowConv), 1, 'descend');
            totalSkelProps = sortrows(regionprops3(totalSkelConv), 1, 'descend');
            aboveSkelProps = sortrows(regionprops3(aboveSkelConv), 1, 'descend');
            belowSkelProps = sortrows(regionprops3(belowSkelConv), 1, 'descend');
                        
            totalProps = totalProps(1, :); % Handle edge case where two volumes are in the hull
            aboveProps = aboveProps(1, :);
            belowProps = belowProps(1, :);
            totalSkelProps = totalSkelProps(1, :);
            aboveSkelProps = aboveSkelProps(1, :);
            belowSkelProps = belowSkelProps(1, :);
            if options.cProjF % Create projections flag set
                origStack = imresize3(flip(origStack, 1), 'Scale', [1 1 ZScale]); % 2 of 2 volume resize operations in function
                aFString = string(double(options.aF));
                bFString = string(double(options.bF));
                tFString = string(double(options.tF));
                
                aSFString = string(double(options.aSF));      % Skel convex hulls
                bSFString = string(double(options.bSF));
                tSFString = string(double(options.tSF));
                combF = strcat(aFString, bFString, tFString); % combined flag (hull outline)
                combFSkel = strcat(aSFString, bSFString, tSFString);
                for N = 1:3                    
                    totalPerim = bwperim(imrotate(squeeze(max(totalConv, [], N)), 90));
                    abovePerim = bwperim(imrotate(squeeze(max(aboveConv, [], N)), 90));
                    belowPerim = bwperim(imrotate(squeeze(max(belowConv, [], N)), 90));
                    
                    totalSkelPerim = bwperim(imrotate(squeeze(max(totalSkelConv, [], N)), 90));
                    aboveSkelPerim = bwperim(imrotate(squeeze(max(aboveSkelConv, [], N)), 90));
                    belowSkelPerim = bwperim(imrotate(squeeze(max(belowSkelConv, [], N)), 90));  
                                  
                    forLine = false(size(BW));
                    rTBCentroidRCP = round(TBCentroidRCP);
                    raCentroidRCP = round(aCentroidRCP);
                    rbCentroidRCP = round(bCentroidRCP);
                    
                    forLine(rTBCentroidRCP(1, 1), rTBCentroidRCP(1, 2), rTBCentroidRCP(1, 3)) = true; % Just TB centroid (volume)
                    aforLine = forLine;
                    bforLine = forLine;
                    
                    aforLine(raCentroidRCP(1, 1), raCentroidRCP(1, 2), raCentroidRCP(1, 3)) = true; % Just above centroid (volume)
                    bforLine(rbCentroidRCP(1, 1), rbCentroidRCP(1, 2), rbCentroidRCP(1, 3)) = true; % Just below centroid (volume)
                    
                    aProjection = imrotate(squeeze(max(aforLine, [], N)), 90); % Convert to a 2D projection image (single white pixel at TB centroid and A centroid)
                    bProjection = imrotate(squeeze(max(bforLine, [], N)), 90);
                    aforL = [];
                    bforL = [];
                    [aforL(:, 1), aforL(:, 2)] = ind2sub(size(aProjection), find(aProjection)); % Find XY (or whatever coordinates for this projection) locations of above and TB centroid in image
                    [bforL(:, 1), bforL(:, 2)] = ind2sub(size(bProjection), find(bProjection));
                    
                    alinePts = [];
                    blinePts = [];
                    if numel(aforL) < 4 % A perfectly straight line in this projection (only one point - bresenham will fail)
                        alinePts = aforL;
                    else
                        [alinePts(:, 1), alinePts(:, 2)] = app.bresenham_F(aforL(1,1), aforL(1,2), aforL(2, 1), aforL(2, 2)); % Points for line between the two centroids
                    end
                    if numel(bforL) < 4 % A perfectly straight line in this projection (only one point - bresenham will fail)
                        blinePts = bforL;
                    else
                        [blinePts(:, 1), blinePts(:, 2)] = app.bresenham_F(bforL(1,1), bforL(1,2), bforL(2, 1), bforL(2, 2));
                    end                                                      
                    maskedOrigStack = origStack;
                    maskedOrigStack(~totalConv) = 0;
                    origP = imrotate(squeeze(max(maskedOrigStack, [], N)), 90); % Original projection using convex hull as mask to eliminate everything else
                    origP = imadjust(mat2gray(origP));
                    aLineMask = false(size(aProjection));
                    bLineMask = false(size(bProjection));                    
                    ainds = sub2ind(size(origP), alinePts(:, 1), alinePts(:, 2));
                    binds = sub2ind(size(origP), blinePts(:, 1), blinePts(:, 2));                    
                    aLineMask(ainds) = true; % logical mask for line from TB centroid to above volume (not above convex hull)
                    bLineMask(binds) = true; 
                    if options.skelF % Draw skeleton as first layer (overwrites origP, so be careful about using in any future updates)
                        skelProj = imrotate(squeeze(max(skel, [], N)), 90);
                        origP = imoverlay(origP, skelProj, options.skelC);
                    end
                    switch combFSkel % [aboveSkelPerim, belowSkelPerim, and totalSkelPerim]
                        case "000" % No skeleton convex hulls, do nothing
                        case "001" % Total skel conv perim only
                            origP = imoverlay(origP, totalSkelPerim, options.tSColor);
                        case "010" % Below skel conv perim only
                            origP = imoverlay(origP, belowSkelPerim, options.bSColor);
                        case "011" % Below and total
                            wTotal = imoverlay(origP, totalSkelPerim, options.tSColor);
                            origP = imoverlay(wTotal, belowSkelPerim, options.bSColor);
                        case "100" % Above skel conv perim only
                            origP = imoverlay(origP, aboveSkelPerim, options.aSColor);
                        case "101" % Above and total
                            wTotal = imoverlay(origP, totalSkelPerim, options.tSColor);
                            origP = imoverlay(wTotal, aboveSkelPerim, options.aSColor);
                        case "110" % Above and below
                            wAbove = imoverlay(origP, aboveSkelPerim, options.aSColor);
                            origP = imoverlay(wAbove, belowSkelPerim, options.bSColor);
                        case "111" % All
                            wTotal = imoverlay(origP, totalSkelPerim, options.tSColor);
                            wTA = imoverlay(wTotal, aboveSkelPerim, options.aSColor);
                            origP = imoverlay(wTA, belowSkelPerim, options.bSColor);
                        otherwise  % Default to all
                            beep;
                            wTotal = imoverlay(origP, totalSkelPerim, options.tSColor);
                            wTA = imoverlay(wTotal, aboveSkelPerim, options.aSColor);
                            origP = imoverlay(wTA, belowSkelPerim, options.bSColor);
                    end
                    wTotal = []; %#ok<NASGU> 
                    wAbove = []; %#ok<NASGU> 
                    wTA = [];    %#ok<NASGU> 
                    switch combF   % Using [abovePerim, belowPerim, and totalPerim] overlaid on origP
                        case "000" % No hulls
                            imToWrite = origP;
                        case "001" % Only total hull
                            imToWrite = imoverlay(origP, totalPerim, options.tC);
                        case "010" % Only below hull
                            imToWrite = imoverlay(origP, belowPerim, options.bC);
                        case "011" % Below + total hull
                            wTotal = imoverlay(origP, totalPerim, tColor); % With multiple always start with total perim first
                            imToWrite = imoverlay(wTotal, belowPerim, options.bC);
                        case "100" % Only above hull
                            imToWrite = imoverlay(origP, abovePerim, options.aC);
                        case "101" % Above + total hull
                            wTotal = imoverlay(origP, totalPerim, options.tC);
                            imToWrite = imoverlay(wTotal, abovePerim, options.aC);
                        case "110" % Above + below hull
                            wAbove = imoverlay(origP, abovePerim, options.aC);
                            imToWrite = imoverlay(wAbove, belowPerim, options.bC);
                        case "111" % All hulls
                            wTotal = imoverlay(origP, totalPerim, options.tC);
                            wTA = imoverlay(wTotal, abovePerim, options.aC);
                            imToWrite = imoverlay(wTA, belowPerim, options.bC);
                        otherwise  % Default to all hulls
                            beep;
                            wTotal = imoverlay(origP, totalPerim, options.tC);
                            wTA = imoverlay(wTotal, abovePerim, options.aC);
                            imToWrite = imoverlay(wTA, belowPerim, options.bC);
                    end
                    if options.aLineF && options.bLineF % Draw both centroid lines
                        wALine = imoverlay(imToWrite, aLineMask, options.aLineC);
                        imToWrite = imoverlay(wALine, bLineMask, options.bLineC);
                    elseif options.aLineF
                        imToWrite = imoverlay(imToWrite, aLineMask, options.aLineC);
                    elseif options.bLineF
                        imToWrite = imoverlay(imToWrite, bLineMask, options.bLineC);
                    end
                    projData(N).Projection = imToWrite; %#ok<AGROW> 
                end
            else
                projData(1).Projection= [];
            end           
            data.TBZlevel = TBZlevel;
            data.matlabTBZlevel = zLevel;     % TBZlevel as used by matlab
            data.totalExtentX = XYZextent(1);
            data.totalExtentY = XYZextent(2);
            data.totalExtentZ = XYZextent(3);
            data.aboveExtentX = aXYZextent(1);
            data.aboveExtentY = aXYZextent(2);
            data.aboveExtentZ = aXYZextent(3);
            data.belowExtentX = bXYZextent(1);
            data.belowExtentY = bXYZextent(2);
            data.belowExtentZ = bXYZextent(3);
            data.totalConvexHullVol = totalProps.Volume;
            data.aboveConvexHullVol = aboveProps.Volume;
            data.belowConvexHullVol = belowProps.Volume;
            data.totalSkelConvexHullVol = totalSkelProps.Volume;
            data.aboveSkelConvexHullVol = aboveSkelProps.Volume;
            data.belowSkelConvexHullVol = belowSkelProps.Volume;
            data.totalArborVolume = numel(find(BW));
            data.aboveArborVolume = numel(find(aboveBW));
            data.belowArborVolume = numel(find(belowBW));
            data.totalCentroidX = totalCentroid(1);
            data.totalCentroidY = totalCentroid(2);
            data.totalCentroidZ = totalCentroid(3);
            data.aboveCentroidX = aCentroid(1);
            data.aboveCentroidY = aCentroid(2);
            data.aboveCentroidZ = aCentroid(3);
            data.belowCentroidX = bCentroid(1);
            data.belowCentroidY = bCentroid(2);
            data.belowCentroidZ = bCentroid(3);
            data.TBCentroidX = TBCentroid(1);
            data.TBCentroidY = TBCentroid(2);
            data.TBCentroidZ = TBCentroid(3);            
            data.totalTree = tTree;
            data.aboveTree = aTree;
            data.belowTree = bTree;
            data.totalSkelLen = tLen;
            data.aboveSkelLen = aLen;  
            data.belowSkelLen = bLen;            
            data.aVsTBCentroidDistance = norm(aCentroid - TBCentroid);
            data.bVsTBCentroidDistance = norm(bCentroid - TBCentroid);
        end
        
        function volToConvHullSTL_F(~, BW, filename)
            [RCP(:, 1), RCP(:, 2), RCP(:, 3)] = ind2sub(size(BW), find(BW));
            convHullAlphaShape = alphaShape(RCP, Inf);
            [boundFacets, P] = convHullAlphaShape.boundaryFacets();
            outName = char(strcat(filename, '-CONVHULL.stl'));
            stlwrite(triangulation(boundFacets, P), outName);
        end
               
        
        function [tTree, aTree, bTree, tLen, aLen, bLen] = genScaledTrees_F(~, unscaledSkel, XYpixPerMicron, Zscale, Zlevel)
            %{
            % genScaledTrees_F
            %
            % PURPOSE: Generate Treestoolbox trees and get lengths for
            %   total, above, and below skeletons.
            %
            % INPUTS:
            %   - unscaledSkel = Unscaled skeleton with tastebud Z level.
            %   and cutoff removal already applie. Logical 3D array.
            %
            %   - XYpixPerMicron = the XY scale (set during extraction mode).
            %
            %   - Zscale = As read from GUI. Used with XYpixPerMicron to
            %   get Z conversion factor to be equal to XY conversion factor.
            %
            %   - Zlevel = To seperate unscaledSkel into total/above/below.
            %
            % OUTPUTS:
            %   - A tree structure for total, above and below.
            %   - Lengths of each of these in micrometers.
            %   
            %
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2020b but may work
            % 	on earlier versions). Also:
            %       - The treestoolbox (Copyright (C) 2009 - 2018  Hermann Cuntz)
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 04-2021.
            %
            % NOTES:
            %   - aboveLength + belowLength won't always be equal to
            %   totalLength. This script calls MST tree three times (for T, A,
            %   and B volumes seperately). MST can connect above-> below in the 
            %   total volume for potentially shorter paths than above and below seperately.
            %}
            if ~isdeployed; addpath(genpath('treestoolbox-master')); end
            %% Controls for MST and smoothing
            bf = 0; % Balance factor for MST. Default 0.4.
            asymWeight = 0.5; % Default 0.5 (Between 0.5 and 1). For smooth_tree.
            smoothProp = 0.9; % Default 0.9 (Between 0 and 1). For smooth_tree.
            iterations = 5; % Default 5. For smooth_tree.
            %% Set scale factors
            micPerPix = 1/XYpixPerMicron;
            finZmicPerPix = micPerPix/(1/Zscale);
            XYZScale = [micPerPix micPerPix finZmicPerPix]; % Assuming X and Y have same scale
            totalSkelUnscaled = unscaledSkel;
            aboveSkelUnscaled = unscaledSkel;
            belowSkelUnscaled = unscaledSkel;
            %% Seperate above/below portions and get XYZ coords for total, above and below
            aboveSkelUnscaled(:, :, 1:Zlevel-1) = false;
            belowSkelUnscaled(:, :, Zlevel:end) = false;
            [tX, tY, tZ] = ind2sub(size(totalSkelUnscaled), find(totalSkelUnscaled));
            [aX, aY, aZ] = ind2sub(size(aboveSkelUnscaled), find(aboveSkelUnscaled));
            [bX, bY, bZ] = ind2sub(size(belowSkelUnscaled), find(belowSkelUnscaled));
            %% Get MST for unscaled trees, then repair+clean
            [tTree, ~] = MST_tree(1, tX, tY, tZ, bf);
            [aTree, ~] = MST_tree(1, aX, aY, aZ, bf);
            [bTree, ~] = MST_tree(1, bX, bY, bZ, bf);
            tTree.name = 'Total tree';
            aTree.name = 'Above tree';
            bTree.name = 'Below tree';
            tTree = repair_tree(tTree);
            aTree = repair_tree(aTree);
            bTree = repair_tree(bTree);
            tTree = clean_tree(tTree);
            aTree = clean_tree(aTree);
            bTree = clean_tree(bTree);
            %% Smooth unscaled trees using smooth_tree
            try % Skip smoothing if fails
                tTree = smooth_tree(tTree, asymWeight, smoothProp, iterations);
            catch
            end
            try
                aTree = smooth_tree(aTree, asymWeight, smoothProp, iterations);
            catch
            end
            try
                bTree = smooth_tree(bTree, asymWeight, smoothProp, iterations);
            catch
            end
            %% Scale tree so units are correct, then repair+clean as final step
            tTree = scale_tree(tTree, XYZScale);
            aTree = scale_tree(aTree, XYZScale);
            bTree = scale_tree(bTree, XYZScale);
            tTree = repair_tree(tTree);
            aTree = repair_tree(aTree);
            bTree = repair_tree(bTree);
            tTree = clean_tree(tTree);
            aTree = clean_tree(aTree);
            bTree = clean_tree(bTree);
            %% Get total arbor length from finished trees (micrometers)
            tGenes = gene_tree({{tTree}});
            tGenes = tGenes{1, 1};
            tLen = sum(tGenes(:, 1)); % In micrometers            
            aGenes = gene_tree({{aTree}});
            aGenes = aGenes{1, 1};
            aLen = sum(aGenes(:, 1)); % In micrometers            
            bGenes = gene_tree({{bTree}});
            bGenes = bGenes{1, 1};
            bLen = sum(bGenes(:, 1)); % In micrometers            
        end
        
        function errFlag = checkForErrFlags_F(~, BW, aboveBW, belowBW, skel, aboveSkel, belowSkel)
            %{
            % checkForErrFlags_F
            %
            % PURPOSE: Small helper function to check if any errFlags 
            %  should be set from input data (not enough points in above volume etc).
            %
            % INPUTS: total volume then also above, and below volumes + skeletons before scaling 
            %
            % OUTPUTS:
            %   - errFlag (see manual)
            %   
            % DEPENDENCIES: Basic MATLAB install (built/tested on R2021a but may work
            % 	on earlier versions).
            %
            % AUTHOR: David C Alston (dalston2428@gmail.com) 07-2021.
            %
            % NOTES:
            %}
            errFlag = 0;            
            [RCP(:, 1), RCP(:, 2), RCP(:, 3)] = ind2sub(size(BW), find(BW)); % ind2sub returns as row, col, plane
            try % Test if points coplanar in entire volume (can't generate a 3D convex hull of coplanar points)
                convhull(RCP); % Fails if coplanar or not enough points
            catch
                errFlag = 1;
                return
            end
            %------------------------------------------------------------------------------------------------
            [aRCP(:, 1), aRCP(:, 2), aRCP(:, 3)] = ind2sub(size(aboveBW), find(aboveBW)); % Points above taste bud Z level
            try
                convhull(aRCP); % Fails if coplanar or not enough points
            catch 
                errFlag = 2;
                return
            end
            %------------------------------------------------------------------------------------------------
            [bRCP(:, 1), bRCP(:, 2), bRCP(:, 3)] = ind2sub(size(belowBW), find(belowBW));
            try
                convhull(bRCP);
            catch
                errFlag = 3;
                return
            end
            %------------------------------------------------------------------------------------------------
            [RCPs(:, 1), RCPs(:, 2), RCPs(:, 3)] = ind2sub(size(skel), find(skel)); % ind2sub returns as row, col, plane
            try
                convhull(RCPs);
            catch
                errFlag = 4;
                return
            end
            %------------------------------------------------------------------------------------------------
            [aRCPs(:, 1), aRCPs(:, 2), aRCPs(:, 3)] = ind2sub(size(aboveSkel), find(aboveSkel)); % Points above taste bud Z level
            try
                convhull(aRCPs);
            catch
                errFlag = 5;
                return
            end
            %------------------------------------------------------------------------------------------------
            [bRCPs(:, 1), bRCPs(:, 2), bRCPs(:, 3)] = ind2sub(size(belowSkel), find(belowSkel));
            try
                convhull(bRCPs);
            catch
                errFlag = 6;
                return
            end          
        end
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            app.DandELabel.Text = strcat(sprintf(char(948)), '/', sprintf(char(949)));
            app.GEditFieldLabel.Text = sprintf(char(947));
            %% Handle initial control/panel hiding/disabling - Extraction mode
            app.SelectfileDropDown.Visible = 'off';
            app.SelectfileDropDownLabel.Visible = 'off';
            app.segSkelTabGroup.Visible = 'off';
            app.VolumeremovalPanel.Visible = 'off';            
            app.WritedatafileButton.Visible = 'off';           
            app.CropstackButton.Visible = 'off';
            app.CropModeButton.Visible = 'off';
            app.SelectcropwindowLamp.Visible = 'off';
            %% Handle initial control/panel hiding/disabling - Analysis mode
            app.ProcessingcontrolsPanel.Visible = 'off';
            app.CreateProjectionsCheckbox.Visible = 'off';
            app.ProjectionControlsPanel.Visible = 'off';
            app.StartProcessingButton.Visible = 'off';
            app.TBZxlsxButton.Visible = 'off';
            app.TBZxlsxLamp.Visible = 'off';
            app.SelectOutputFolderButton.Visible = 'off';
            app.SelectOutputFolderLamp.Visible = 'off';
            app.CreateSWCfilesCheckBox.Visible = 'off';
            %% Remove axes ticks, maximize window etc.
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            screenSize = get(groot,'ScreenSize'); % Used to help reposition figure on diff sized monitors
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.ArborTools.Position = [left bottom width height];
            app.ArborTools.WindowState = 'maximized';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ArborTools.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {859, 859};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {449, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            % Select folder with TIF image files            
            app.E_TIFFolderPath = uigetdir([], 'Select folder containing TIF image stacks');
            if app.E_TIFFolderPath == 0; return; end % User hit cancel before selecting folder            
            [app.E_files, numFiles] = app.getFiles_F(app.E_TIFFolderPath, '.tif');
            if numFiles < 1
                beep;
                uialert(app.ArborTools, 'No .tif files found. Check the folder you selected', 'Invalid folder');
                return
            end
            for S = 1:numFiles % Sort files by timepoint (looks for t then one to six digits followed by a space)
                currFilename = app.E_files{S, 2};
                tIdx = strfind(currFilename, 't');
                tIdx = tIdx(1); % Ignore the t in '.mat';
                for B = 0:6
                    currStr = currFilename(tIdx+B);
                    if strcmp(currStr, ' '); break; end
                end
                if B>0
                    tPoint = str2double(currFilename(tIdx+1:tIdx+B));
                else % Single digit timepoint
                    tPoint = str2double(currFilename(tIdx+1));
                end
                app.E_files{S, 3} = tPoint;
            end
            app.E_files = sortrows(app.E_files, 3); % Sort by timepoint (row 1 = t0 , row 2 = t12 etc.)
            TIFNames = app.E_files(:, 2);
            TIFPaths = app.E_files(:, 1);
            app.SelectfileDropDown.ItemsData = {};
            app.SelectfileDropDown.Items = {};
            app.SelectfileDropDown.Items = TIFNames;
            app.SelectfileDropDown.ItemsData = TIFPaths;
            app.SelectfileDropDown.Visible = 'on';
            app.SelectfileDropDownLabel.Visible = 'on';
            app.SelectfileDropDownValueChanged(); % Load the first image (as a default)
            app.SelectfolderLamp.Color = [0 1 0];
        end

        % Button pushed function: WritedatafileButton
        function WritedatafileButtonPushed(app, event)
            close all
            loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Please wait', 'Message', 'Writing .mat data file...', 'Indeterminate' ,'on');
            app.WritedatafileButton.Enable = 'off';
            drawnow;
            outData.dataVersion = '4-29-2021'; % DEV TODO update this if extraction mode data changes
            outData.XYSizeMicron = app.E_XYSizeMicrons;
            outData.XYPixPerMicron = app.E_XYPixPerMicron;
            outData.Pvars.abc = [app.aandbSpinner.Value app.aandbSpinner.Value app.cSpinner.Value];
            outData.Pvars.scale = app.E_scale;
            outData.Pvars.delta = app.DeltaEpsiEditField.Value;
            outData.Pvars.gamma = app.GEditField.Value;
            outData.Pvars.epsilon = app.DeltaEpsiEditField.Value;
            outData.Pvars.SE3Dpix = app.E_SE3DSize;
            outData.origStack = app.E_origStack;
            outData.polyStack = app.E_polyStack;   % Only if using polygonal cropping
            outData.polyMask3D = app.E_polyMask3D; % Only if using polygonal cropping
            outData.cropCuboid = app.E_cropCuboid;            
            outData.ZProj = app.E_ZProjection;
            outData.PLabeledOrig = app.E_PLabeled;
            outData.finalLabelVol = app.E_altLabel;
            outData.IDsRemoved = app.E_IDsRemoved;
            outData.skelVol = app.E_skelVol;
            outData.skelBranchPts = app.E_skelBPts;
            outData.skelEndPts = app.E_skelEndPts;
            % This is a temp check until this bug occurs again so it can be
            % reproduced (Z levels not the same between entries). 4-30-2021
            % DEV TODO
            chkSize = size(outData.origStack);
            T1 = ~all(chkSize == size(outData.finalLabelVol)); % If == 1, bug occured
            T2 = ~all(chkSize == size(outData.skelVol));
            if T1 || T2
                uialert(app.ArborTools, 'Bug with different Z levels in data structure. Send extraction settings/file used to David', 'Z level bug occured');
            end
            outName = strcat(erase(app.SelectfileDropDown.Value, '.tif'), '-DATA.mat');
            save(outName, 'outData');
            app.WritedatafileButton.Enable = 'on';
            drawnow;
            close(loadBar);
        end

        % Button pushed function: RemoveVolsButton
        function RemoveVolsButtonPushed(app, event)
           close all
           loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Please wait', 'Message', 'Removing volume(s)...', 'Indeterminate' ,'on');
           currTable = app.UITable.DisplayData;
           remVector = currTable.Keep; % Single column vector of 0 or 1 for each. 1 = keep volume, 0 = remove volume
           IDVector = currTable.ID;           
           B = 0;
           app.E_altLabel = app.E_PLabeled; % Grab the P labeled volume
           visVector = true(numel(remVector), 1);
           for N = 1:numel(remVector)
                if remVector(N, 1) % Keep volume
                    continue
                else               % Remove volume
                    B = B+1;
                    visVector(IDVector(N, 1), 1) = false;
                    idx = (app.E_PLabeled == IDVector(N, 1));
                    app.E_IDsRemoved(B, 1) = IDVector(N, 1);
                    app.E_altLabel(idx) = 0; % What gets written to file, not what is shown. Just use labelvolshow 'LabelVisibility'
                end
           end
           IDsortedTable = sortrows(currTable, 1);
           sortedColors = IDsortedTable.Colors;           % Since labelvolshow assumes row number = label #
           visVector = [false; visVector];                % For label 0
           sortedColors = vertcat([0 0 0], sortedColors); % For label 0
           labelHandle = figure('Name', 'Labeled volume (some volumes removed)', 'NumberTitle', 'off');
           labelvolshow(app.E_PLabeled, 'Parent', labelHandle, 'BackgroundColor', [1 1 1], 'LabelColor', sortedColors, 'LabelVisibility', visVector);
           close(loadBar);
        end

        % Button pushed function: ApplyPFilterButton
        function ApplyPFilterButtonPushed(app, event)
            close all % Close any volume figures that are open
            app.WritedatafileButton.Visible = 'off'; % Turned on by skeletonization button
            volToKeep = 60; % Keep this many of the largest volumes (labelvolshow can only show 128 labels maximum)
            loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Please wait', 'Message', 'Segmenting...', 'Indeterminate' ,'on');
            app.E_SE3DSize = app.ClosingelementsizeSpinner.Value;
            app.E_scale = app.PrescaleSpinner.Value;
            YSizePix = size(app.E_origStack, 1); % # rows
            app.E_XYPixPerMicron = YSizePix / app.XYsizemicronsEditField.Value; % Force an update (user could change value after file selection)
            oneMicronPix = nearest(app.E_XYPixPerMicron);
            tophatSE = strel('rectangle', [oneMicronPix oneMicronPix]);            
            topFiltered = zeros(size(app.E_cropStack));
            for T = 1:size(app.E_cropStack, 3) % Top hat filter with 1um x 1um rectangular element
                topFiltered(:, :, T) = imtophat(app.E_cropStack(:, :, T), tophatSE);
            end
            [PFiltered, ~] = app.langP_F(topFiltered, app.E_scale);
            if app.E_SE3DSize > 0 % Closing if desired
                structElement = strel(ones(app.E_SE3DSize, app.E_SE3DSize, app.E_SE3DSize));
                PFiltered = imclose(PFiltered, structElement);
            end
            X = fix(app.E_cropCuboid(1));
            Y = fix(app.E_cropCuboid(2));
            Xw = fix(app.E_cropCuboid(4)); % X width
            Yw = fix(app.E_cropCuboid(5));
            newPFilt = false(size(app.E_origStack));
            newPFilt(Y:Y+Yw, X:X+Xw, :) = PFiltered; % Pad with 0 to return to full size (undo the cropping)
            app.E_PFiltered = newPFilt;
            loadBar.Message = 'Converting to label array...'; % 02-2021 BUG - Occasional hangup at some point after this?
            props = sortrows(regionprops3(app.E_PFiltered), 1, 'descend');
            if size(props, 1) > volToKeep % Only keep ~volToKeep largest volumes
                maxVol = props{volToKeep, 1};
                app.E_PFiltered = bwareaopen(app.E_PFiltered, maxVol);
            end
            [app.E_PLabeled, n] = bwlabeln(app.E_PFiltered);
            labelProps = regionprops3(app.E_PLabeled, 'Volume', 'Image');
            app.UITable.Data = [];
            Colors = rand(n, 3); % Colors set, don't change from here
            ID = (1:1:n)';
            catTable = table(ID, Colors);
            col0 = [0 0 0]; % regionprops3 does not select background (Label = 0), need to specify color for this
            col0 = vertcat(col0, Colors); % Colors does not have the color for label 0. Added using col0 for labelvolshow()
            app.E_labelData = [labelProps catTable]; % Table with columns: Volume Image ID Colors
            labelHandle = figure('Name', 'Labeled volume', 'NumberTitle', 'off');
            labelvolshow(app.E_PLabeled, 'Parent', labelHandle, ...
                'BackgroundColor', [1 1 1], 'LabelColor', col0);       % Need to input colors in index order for labelvolshow()       
            app.E_labelData = sortrows(app.E_labelData, 1, 'descend'); % Sort by descending volume           
            ID = app.E_labelData.ID;
            Volume = app.E_labelData.Volume;
            Colors = app.E_labelData.Colors;
            Keep = false(size(ID));
            tableToShow = table(ID, Volume, Keep, Colors);
            app.UITable.Data = tableToShow;
            for N = 1:size(ID, 1) % Create styles in order to set color column to match the shown colors in labelvolshow()
                currStyle = uistyle('BackgroundColor', Colors(N, :));
                addStyle(app.UITable, currStyle, 'cell', [N, 4]);
            end
            close(loadBar);
            app.VolumeremovalPanel.Visible = 'on';
        end

        % Value changed function: SelectfileDropDown
        function SelectfileDropDownValueChanged(app, event)
            % Load and invert stack, also set XY scale factor
            clc
            app.updateAxes_2D([]);
            app.segSkelTabGroup.Visible = 'off';
            app.VolumeremovalPanel.Visible = 'off';
            app.WritedatafileButton.Visible = 'off';
            app.CropstackButton.Visible = 'off';
            app.SelectcropwindowLamp.Visible = 'off';
            app.SelectcropwindowLamp.Color = [1 0 0];
            app.BranchcandidatesEditField.Value = 0;
            app.EndpointcandidatesEditField.Value = 0;
            app.E_currTIF = app.SelectfileDropDown.Value; % Full path to currently selected TIF stack
            loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Please wait', 'Message', 'Loading image...');
            rawStack = app.readTIFFStack_F(app.E_currTIF);
            invStack = zeros(size(rawStack));
            for N = 1:size(rawStack, 3) % Inverting stack order
                loadBar.Value = N/size(rawStack, 3);
                stackID = size(rawStack, 3)-N+1;
                invStack(:, :, N) = rawStack(:, :, stackID);
            end
            app.E_origStack = invStack;
            YSizePix = size(app.E_origStack, 1); % # rows
            app.E_XYPixPerMicron = YSizePix / app.XYsizemicronsEditField.Value;
            app.E_XYSizeMicrons = [app.XYsizemicronsEditField.Value app.XYsizemicronsEditField.Value]; % Assuming square slices
            close(loadBar);
            app.CropstackButton.Visible = 'on';
            app.CropModeButton.Visible = 'on';
            app.SelectcropwindowLamp.Visible = 'on';
        end

        % Button pushed function: CropstackButton
        function CropstackButtonPushed(app, event)
            close all
            app.E_cropStack = [];
            app.E_cropCuboid = [];
            app.E_polyMask3D = [];
            app.E_polyStack = [];
            app.E_cropCuboid = [];
            app.WritedatafileButton.Visible = 'off'; % Turned on by skeletonization button
            cropMode = app.CropModeButton.Value;     % 1 = Poly crop, 0 = simple crop
            depth = size(app.E_origStack, 3)-1;
            loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Please wait', 'Message', 'Generating enhanced 2D projections...', 'Indeterminate' ,'on');
            [P1, P2, P3] = app.genProjections_F(app.E_origStack, 3);
            app.E_ZProjection = P3; % Top down projection
            close(loadBar);
            lWidth = 1;
            if cropMode == 1 % Polygonal crop
                app.updateAxes_2D(P1);
                p1Fig = figure('Name', 'Draw a polygon around the arbor point by point (P1)', 'NumberTitle', 'off', 'WindowState', 'maximized');
                imshow(P1);
                p1ROI = images.roi.Polygon();
                p1ROI.LineWidth = lWidth;
                draw(p1ROI);
                p1Mask = createMask(p1ROI); % 2D logical array
                close(p1Fig);               
                app.updateAxes_2D(P2);
                p2Fig = figure('Name', 'Draw a polygon around the arbor point by point (P2)', 'NumberTitle', 'off', 'WindowState', 'maximized');
                imshow(P2);
                p2ROI = images.roi.Polygon();
                p2ROI.LineWidth = lWidth;
                draw(p2ROI);
                p2Mask = createMask(p2ROI);
                close(p2Fig);                
                app.updateAxes_2D(P3);
                p3Fig = figure('Name', 'Draw a polygon around the arbor point by point (P3)', 'NumberTitle', 'off', 'WindowState', 'maximized');
                imshow(P3);
                p3ROI = images.roi.Polygon();
                p3ROI.LineWidth = lWidth;
                draw(p3ROI);
                p3Mask = createMask(p3ROI);
                close(p3Fig);                
                xSize = size(app.E_origStack, 1);
                ySize = size(app.E_origStack, 2);
                zSize = size(app.E_origStack, 3);
                p1Vol = false(size(app.E_origStack));
                p2Vol = p1Vol;
                p3Vol = p1Vol;
                for X = 1:xSize; p1Vol(X, :, :) = p1Mask; end
                for Y = 1:ySize; p2Vol(:, Y, :) = p2Mask; end
                for Z = 1:zSize; p3Vol(:, :, Z) = p3Mask; end
                mask3D = and(and(p1Vol, p2Vol), p3Vol);
                app.E_polyMask3D = mask3D;
                app.E_polyStack = app.E_origStack;
                app.E_polyStack(~mask3D) = 0; % Everything outside polygons set to 0, Finalizes E_polyStack.
                loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Please wait', 'Message', 'Generating convex hull for bounding box...', 'Indeterminate' ,'on');
                maskHull = app.BWVol2CHullMask_F(mask3D); % Issue where mask3D creates strange bounding box, swiched to using cHull for bounding box          
                BB = regionprops3(maskHull, 'BoundingBox').BoundingBox; % 3D bounding box (used for 3D cropping)
                BB(BB<1) = 1;
                app.E_cropCuboid = [BB(1), BB(2), 1, BB(4), BB(5), depth]; % Mimic old rectangular cropping (keep all Z levels)
                app.E_cropStack = imcrop3(app.E_polyStack, app.E_cropCuboid);
                close(loadBar);
            else % Simple rectangular crop
                app.updateAxes_2D(app.E_ZProjection);
                newFig = figure('Name', 'Drag and draw crop box then double left click inside box', 'NumberTitle', 'off', 'WindowState', 'maximized');
                imshow(app.E_ZProjection);
                [~, rect] = imcrop(newFig);
                if isempty(rect); return; end % User closed crop window
                close(newFig);
                app.E_cropCuboid = [rect(1), rect(2), 1, rect(3), rect(4), depth];
                app.E_cropStack = imcrop3(app.E_origStack, app.E_cropCuboid);
            end
            app.SelectcropwindowLamp.Color = [0 1 0];
            app.segSkelTabGroup.Visible = 'on';
        end

        % Value changed function: XYsizemicronsEditField
        function XYsizemicronsEditFieldValueChanged(app, event)
            app.E_XYSizeMicrons = [app.XYsizemicronsEditField.Value app.XYsizemicronsEditField.Value];            
        end

        % Close request function: ArborTools
        function ArborToolsCloseRequest(app, event)
            delete(app);
            close all
        end

        % Button pushed function: SelectmatFolderButton
        function SelectmatFolderButtonPushed(app, event)
            app.ProcessingcontrolsPanel.Visible = 'off'; % In case selecting new folder after already processing/selecting one
            app.CreateProjectionsCheckbox.Visible = 'off';
            app.ProjectionControlsPanel.Visible = 'off';
            app.StartProcessingButton.Visible = 'off';
            app.TBZxlsxButton.Visible = 'off';
            app.TBZxlsxLamp.Visible = 'off';
            app.SelectOutputFolderButton.Visible = 'off';
            app.SelectOutputFolderLamp.Visible = 'off';
            app.CreateSWCfilesCheckBox.Visible = 'off';
            app.SelectmatFolderLamp.Color = [1 0 0];
            app.TBZxlsxLamp.Color = [1 0 0];
            app.SelectOutputFolderLamp.Color = [1 0 0];
            app.A_matFolderPath = uigetdir([], 'Select folder containing .mat data');
            if app.A_matFolderPath == 0; return; end % User hit cancel before selecting folder            
            [app.A_files, numFiles] = app.getFiles_F(app.A_matFolderPath, '.mat');
            if numFiles < 1
                beep;
                uialert(app.ArborTools, 'No .mat files found. Check the folder you selected', 'Invalid folder');
                return
            end
            app.SelectmatFolderLamp.Color = [0 1 0];
            app.TBZxlsxButton.Visible = 'on';
            app.TBZxlsxLamp.Visible = 'on';
        end

        % Button pushed function: TBZxlsxButton
        function TBZxlsxButtonPushed(app, event)
            [file, path] = uigetfile({'*.xlsx'}, 'Select .xlsx with taste bud Z levels in first row');
            if file == 0; return; end
            app.A_TBZ = readmatrix(strcat(path, file));
            numTBZ = numel(app.A_TBZ);
            numMats = size(app.A_files, 1);
            if numTBZ ~= numMats
                beep;
                dispStr = strcat('# TB Z levels:', int2str(numTBZ), ', # .mats:', int2str(numMats));
                outStr = strcat('The number of entries in the .xlsx does not match the number of .mat files found.', dispStr);
                uialert(app.ArborTools, outStr, '.xlsx/.mat taste bud Z level count mismatch');
                app.A_TBZ = -1;
                return
            end           
            app.TBZxlsxLamp.Color = [0 1 0];
            app.ProcessingcontrolsPanel.Visible = 'on';
            app.CreateProjectionsCheckbox.Visible = 'on';
            app.ProjectionControlsPanel.Visible = 'on';
            app.SelectOutputFolderButton.Visible = 'on';
            app.SelectOutputFolderLamp.Visible = 'on';
            app.CreateSWCfilesCheckBox.Visible = 'on';
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            ZScale = app.ZScaleEditField.Value; % IMPORTANT NOTES IN COMMENT BELOW:
            %{
                - Assume a stack has XYZ microns per pixels = [0.15625 0.15625 1]

                - Scaling Z by 1/0.15625 (6.4) allows you to use the
                0.15625 microns per pixel value across the entire stack.
                Sets the Z scale so that it is the same as the XY scale.

                - Directly scaling volumes by [0.15625 0.15625 1] causes
                resolution issues and data loss. The Z scaling method is much
                better.
            %}
            ZCutoff = app.ZlevelcutoffEditField.Value;
            options.cProjF = app.CreateProjectionsCheckbox.Value; % Create projections flag
            options.aF = app.AboveHullInclude.Value; % Flag to create above convex hull outline on projection
            options.aC = app.PickAboveColorLamp.Color;
            options.bF = app.BelowHullInclude.Value; % Flag to create below convex hull outline on projection
            options.bC = app.PickBelowColorLamp.Color;
            options.tF = app.AllHullInclude.Value;   % Flag to create total convex hull outline on projection
            options.tC = app.PickTotalColorLamp.Color;
            options.aLineF = app.tbToAboveInclude.Value;
            options.bLineF = app.tbToBelowInclude.Value;
            options.aLineC = app.PickAboveLineLamp.Color;
            options.bLineC = app.PickBelowLineLamp.Color;
            options.skelF = app.DrawskeletonCheckBox.Value;
            options.skelC = app.PickSkeletonLamp.Color;
            options.tSF = app.totalSkelHullInclude.Value;
            options.tSColor = app.PickTSkelLamp.Color;
            options.aSF = app.aboveSkelHullInclude.Value;
            options.aSColor = app.PickASkelLamp.Color;
            options.bSF = app.belowSkelHullInclude.Value;
            options.bSColor = app.PickBSkelLamp.Color;
            numFiles = size(app.A_files, 1);
            for S = 1:numFiles % Sort files by timepoint (looks for t then one to six digits followed by a space)
                currFilename = app.A_files{S, 2};
                tIdx = strfind(currFilename, 't');
                tIdx = tIdx(1); % Ignore the t in '.mat';
                for B = 0:6
                    currStr = currFilename(tIdx+B);
                    if strcmp(currStr, ' '); break; end
                end
                if B>0
                    tPoint = str2double(currFilename(tIdx+1:tIdx+B));
                else % Single digit timepoint
                    tPoint = str2double(currFilename(tIdx+1));
                end
                app.A_files{S, 3} = tPoint;
            end
            app.A_files = sortrows(app.A_files, 3); % Now sorted by timepoint descending (row 1 = smallest timepoint)
            loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Processing data', 'Cancelable', 'on');
            for N = 1:numFiles                
                loadBar.Value = N/numFiles;
                loadBar.Message = app.A_files{N, 2};
                tmp = load(app.A_files{N, 1});
                origStack = tmp.outData.origStack;
                pixPerMicron = tmp.outData.XYPixPerMicron; % Set during extraction mode. Z scaling allows this to be used across the whole stack.
                currTimepoint(N, 1) = app.A_files{N, 3};   %#ok<AGROW> DEV
                BW = logical(tmp.outData.finalLabelVol);   % Grab the labeled volume with some labels removed
                skel = tmp.outData.skelVol;
                clearvars tmp
                matZTBLevel = size(BW, 3) - app.A_TBZ(N);
                matEndPtCutoff = matZTBLevel - ZCutoff;
                if matEndPtCutoff < 1; matEndPtCutoff = 1; end
                BW(:, :, 1:matEndPtCutoff) = false;
                skel(:, :, 1:matEndPtCutoff) = false;
                [projData, procData(N, 1), errFlag] = app.genArborData_F(BW, skel, origStack, pixPerMicron, ZScale, app.A_TBZ(N), options); %#ok<AGROW>
                outErrFlag(N, 1) = errFlag; %#ok<AGROW>
                baseName = erase(app.A_files{N, 2}, '-DATA.mat');
                if (app.CreateSWCfilesCheckBox.Value) && ~ismember(errFlag, [1, 4, 7])
                    if ~isdeployed
                        addpath(genpath('treestoolbox-master'));
                    end
                    tTree = procData(N, 1).totalTree;
                    aTree = procData(N, 1).aboveTree;
                    bTree = procData(N, 1).belowTree;
                    outNameT = fullfile(app.A_outputFolder, strcat(baseName, '-totalTree','.swc'));
                    outNameA = fullfile(app.A_outputFolder, strcat(baseName, '-aboveTree','.swc'));
                    outNameB = fullfile(app.A_outputFolder, strcat(baseName, '-belowTree','.swc'));
                    try % DEV TODO Bug function not defined for sparse inputs??? Just skip if occurs
                        swc_tree(tTree, outNameT);
                    catch
                    end
                    try
                        swc_tree(aTree, outNameA);
                    catch
                    end
                    try
                        swc_tree(bTree, outNameB);
                    catch
                    end
                end
                if (options.cProjF) && ~ismember(errFlag, [1, 4, 7])
                    for C = 1:3
                        outName = strcat(baseName, '-cHulls', num2str(C), '.png');
                        outName = fullfile(app.A_outputFolder, outName);
                        imwrite(projData(C).Projection, outName);
                    end
                end
                if loadBar.CancelRequested
                    numFiles = N;
                    break; 
                end

            end
            close(loadBar);
            varNames = {'Time point', 'Micron/Pix', 'errFlag', 'Z level alteration'...
                'T-convH vol', 'A-convH vol', 'B-convH vol', ...
                'T-skel convH vol', 'A-skel convH vol', 'B-skel convH vol', ...
                'T-X width', 'T-Y width', 'T-Z width', ...
                'A-X width', 'A-Y width', 'A-Z width', 'B-X width', 'B-Y width', 'B-Z width', ...
                'T-Centroid X', 'T-Centroid Y', 'T-Centroid Z', ...
                'A-Centroid X', 'A-Centroid Y', 'A-Centroid Z', ...
                'B-Centroid X', 'B-Centroid Y', 'B-Centroid Z', ...
                'T-Arbor length', 'A-Arbor length', 'B-Arbor length', ...
                'Above vs TB centroid distance', 'Below vs TB centroid distance'};
            analysisT = array2table(zeros(numFiles, size(varNames, 2))-1);
            analysisT.Properties.VariableNames = varNames;
            micronPerPix = 1/pixPerMicron;
            cubMicronPerVox = micronPerPix^3;
            convFactorForTable = ones(numel([procData.totalConvexHullVol].'), 1);
            convFactorForTable(:) = micronPerPix;
            try
                analysisT.("Time point") = currTimepoint;
                analysisT.("Micron/Pix") = convFactorForTable;
                analysisT.("errFlag") = outErrFlag;
                analysisT.("Z level alteration") = [procData.modZLevFlag].';
                analysisT.("T-convH vol") = ([procData.totalConvexHullVol].')*cubMicronPerVox;
                analysisT.("A-convH vol") = ([procData.aboveConvexHullVol].')*cubMicronPerVox;
                analysisT.("B-convH vol") = ([procData.belowConvexHullVol].')*cubMicronPerVox; % For tb2 t240 this has two elements? Should have 1                            
                analysisT.("T-skel convH vol") = ([procData.totalSkelConvexHullVol].')*cubMicronPerVox;
                analysisT.("A-skel convH vol") = ([procData.aboveSkelConvexHullVol].')*cubMicronPerVox;
                analysisT.("B-skel convH vol") = ([procData.belowSkelConvexHullVol].')*cubMicronPerVox;            
                analysisT.("T-X width") = ([procData.totalExtentX].')*micronPerPix; % Convex hull extent is the same as volume extent by definition
                analysisT.("T-Y width") = ([procData.totalExtentY].')*micronPerPix;
                analysisT.("T-Z width") = ([procData.totalExtentZ].')*micronPerPix;           
                analysisT.("A-X width") = ([procData.aboveExtentX].')*micronPerPix;
                analysisT.("A-Y width") = ([procData.aboveExtentY].')*micronPerPix;
                analysisT.("A-Z width") = ([procData.aboveExtentZ].')*micronPerPix;            
                analysisT.("B-X width") = ([procData.belowExtentX].')*micronPerPix;
                analysisT.("B-Y width") = ([procData.belowExtentY].')*micronPerPix;
                analysisT.("B-Z width") = ([procData.belowExtentZ].')*micronPerPix;                      
                analysisT.("T-Centroid X") = ([procData.totalCentroidX].')*micronPerPix;
                analysisT.("T-Centroid Y") = ([procData.totalCentroidY].')*micronPerPix;
                analysisT.("T-Centroid Z") = ([procData.totalCentroidZ].')*micronPerPix;           
                analysisT.("A-Centroid X") = ([procData.aboveCentroidX].')*micronPerPix;
                analysisT.("A-Centroid Y") = ([procData.aboveCentroidY].')*micronPerPix;
                analysisT.("A-Centroid Z") = ([procData.aboveCentroidZ].')*micronPerPix;            
                analysisT.("B-Centroid X") = ([procData.belowCentroidX].')*micronPerPix;
                analysisT.("B-Centroid Y") = ([procData.belowCentroidY].')*micronPerPix;
                analysisT.("B-Centroid Z") = ([procData.belowCentroidZ].')*micronPerPix;            
                analysisT.("T-Volume") = ([procData.totalArborVolume].')*cubMicronPerVox;
                analysisT.("A-Volume") = ([procData.aboveArborVolume].')*cubMicronPerVox;
                analysisT.("B-Volume") = ([procData.belowArborVolume].')*cubMicronPerVox;                
                analysisT.("T-Arbor length") = ([procData.totalSkelLen].'); % No conversion factor needed, handled by genScaledTrees_F
                analysisT.("A-Arbor length") = ([procData.aboveSkelLen].');
                analysisT.("B-Arbor length") = ([procData.belowSkelLen].');               
                analysisT.("Above vs TB centroid distance") = ([procData.aVsTBCentroidDistance].')*micronPerPix;
                analysisT.("Below vs TB centroid distance") = ([procData.bVsTBCentroidDistance].')*micronPerPix;          
                date = datetime();
                dateStr = strcat(string(date.Month), '-', string(date.Day), '-', string(date.Year));
                fileName = strcat(dateStr, '.xlsx');
                xlsxName = fullfile(app.A_outputFolder, fileName);
                writetable(analysisT, xlsxName, 'Sheet', 1);
            catch % If xlsx write fails for any reason, write the data as .mat
                date = datetime();
                dateStr = strcat(string(date.Month), '-', string(date.Day), '-', string(date.Year));
                fileName = strcat(dateStr, '-ERR', '.mat');
                matName = fullfile(app.A_outputFolder, fileName);
                save(matName, 'procData');
            end
        end

        % Button pushed function: PickAboveColorButton
        function PickAboveColorButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickAboveColorLamp.Color = setColor; 
            end
        end

        % Button pushed function: PickBelowColorButton
        function PickBelowColorButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickBelowColorLamp.Color = setColor; 
            end
        end

        % Button pushed function: PickTotalColorButton
        function PickTotalColorButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickTotalColorLamp.Color = setColor; 
            end
        end

        % Value changed function: CreateProjectionsCheckbox
        function CreateProjectionsCheckboxValueChanged(app, event)
            if app.CreateProjectionsCheckbox.Value
                app.ProjectionControlsPanel.Visible = 'on';
            else
                app.ProjectionControlsPanel.Visible = 'off';
            end           
        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            app.SelectOutputFolderLamp.Color = [1 0 0];
            app.A_outputFolder = uigetdir([], 'Select folder to put data in');
            if app.A_outputFolder == 0; return; end % User hit cancel before selecting folder            
            app.SelectOutputFolderLamp.Color = [0 1 0];
            app.StartProcessingButton.Visible = 'on';
        end

        % Button pushed function: PickAboveLineButton
        function PickAboveLineButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickAboveLineLamp.Color = setColor;
            end
        end

        % Button pushed function: PickBelowLineButton
        function PickBelowLineButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickBelowLineLamp.Color = setColor;
            end
        end

        % Value changed function: AboveHullInclude
        function AboveHullIncludeValueChanged(app, event)
            if app.AboveHullInclude.Value
                app.PickAboveColorButton.Visible = 'on';
                app.PickAboveColorLamp.Visible = 'on';
            else
                app.PickAboveColorButton.Visible = 'off';
                app.PickAboveColorLamp.Visible = 'off';
            end
            
        end

        % Value changed function: BelowHullInclude
        function BelowHullIncludeValueChanged(app, event)
            if app.BelowHullInclude.Value
                app.PickBelowColorButton.Visible = 'on';
                app.PickBelowColorLamp.Visible = 'on';
            else
                app.PickBelowColorButton.Visible = 'off';
                app.PickBelowColorLamp.Visible = 'off';       
            end
            
        end

        % Value changed function: AllHullInclude
        function AllHullIncludeValueChanged(app, event)
            if app.AllHullInclude.Value
                app.PickTotalColorButton.Visible = 'on';
                app.PickTotalColorLamp.Visible = 'on';
            else
                app.PickTotalColorButton.Visible = 'off';
                app.PickTotalColorLamp.Visible = 'off';                
            end
            
        end

        % Value changed function: tbToAboveInclude
        function tbToAboveIncludeValueChanged(app, event)
            if app.tbToAboveInclude.Value
                app.PickAboveLineButton.Visible = 'on';
                app.PickAboveLineLamp.Visible = 'on';
            else
                app.PickAboveLineButton.Visible = 'off';
                app.PickAboveLineLamp.Visible = 'off';
            end
            
        end

        % Value changed function: tbToBelowInclude
        function tbToBelowIncludeValueChanged(app, event)
            if app.tbToBelowInclude.Value
                app.PickBelowLineButton.Visible = 'on';
                app.PickBelowLineLamp.Visible = 'on';
            else
                app.PickBelowLineButton.Visible = 'off';
                app.PickBelowLineLamp.Visible = 'off';                
            end
            
        end

        % Button pushed function: SkeletonizeButton
        function SkeletonizeButtonPushed(app, event)
            close all % Close any volume figures that are open
            loadBar = uiprogressdlg(app.ArborTools, 'Title', 'Please wait', 'Message', 'Generating skeleton...', 'Indeterminate' ,'on');
            BWVol = logical(app.E_altLabel);
            BWSmth = logical(smooth3(BWVol, 'box', app.SmoothingSizeSpinner.Value));
            if app.ErodevolumeCheckBox.Value
                SE = strel('cube', 3);
                newBW = BWSmth;
                numVol = size(regionprops3(BWSmth), 1);
                for N = 1:10 % Erode until the number of volumes changes, then use N-1 step (maximum erosion)
                    oldVol = newBW;
                    newBW = imerode(newBW, SE);
                    if size(regionprops3(newBW), 1) ~= numVol
                        break
                    end
                end
                BWSmth = oldVol;
            end
            app.E_skelVol = bwskel(BWSmth, 'MinBranchLength', app.MinimumBranchLengthEditField.Value);
            app.E_skelBPts = bwmorph3(app.E_skelVol, 'branchpoints');
            app.E_skelEndPts = bwmorph3(app.E_skelVol, 'endpoints');
            app.BranchcandidatesEditField.Value = numel(find(app.E_skelBPts));
            app.EndpointcandidatesEditField.Value = numel(find(app.E_skelEndPts));
            toShowSkel = double(app.E_skelVol);
            % Check if any of the skel points (branch/end points, etc) are empty
            if sum(sum(sum(app.E_skelVol))) == 0
                beep;
                close(loadBar);
                uialert(app.ArborTools, 'No skeleton found. Check your settings.', 'Skeletonization error');
                return
            end
            if sum(sum(sum(app.E_skelBPts))) == 0 % # of branch points
                beep;
                close(loadBar);
                uialert(app.ArborTools, 'No branch points found. Try a different smoothing setting.', 'Skeletonization error');
                return
            end
            if sum(sum(sum(app.E_skelEndPts))) == 0
                beep;
                close(loadBar);
                uialert(app.ArborTools, 'No end points found. Check your settings.', 'Skeletonization error');
                return
            end
            if sum(sum(sum(BWVol))) == 0
                beep;
                close(loadBar);
                uialert(app.ArborTools, 'No volume found. Check that you have data from the segmentation step.', 'Skeletonization error');
                return
            end
            toShowSkel(BWVol) = 2;
            toShowSkel(app.E_skelVol) = 3;
            toShowSkel(app.E_skelBPts) = 4;
            toShowSkel(app.E_skelEndPts) = 5;
            lOpacity = [0 0.05 1 1 1]';
            lColors = [0 0 0; 1 0 1; 0 1 0; 1 0 0; 0 0 1]; % Magenta transparent arbor, green skeleton, red branch points, blue end points (10-13-2020)
            labelHandle = figure('Name', 'Labeled volume', 'NumberTitle', 'off');
            labelvolshow(toShowSkel, 'Parent', labelHandle, ...
                'BackgroundColor', [1 1 1], 'LabelColor', lColors, 'LabelOpacity', lOpacity);
            app.WritedatafileButton.Visible = 'on';
            close(loadBar);
        end

        % Button pushed function: PickSkeletonButton
        function PickSkeletonButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickSkeletonLamp.Color = setColor;
            end
        end

        % Value changed function: DrawskeletonCheckBox
        function DrawskeletonCheckBoxValueChanged(app, event)
            if app.DrawskeletonCheckBox.Value
                app.PickSkeletonButton.Visible = 'on';
                app.PickSkeletonLamp.Visible = 'on';
            else
                app.PickSkeletonButton.Visible = 'off';
                app.PickSkeletonLamp.Visible = 'off';
            end            
        end

        % Button pushed function: PickTSkelButton
        function PickTSkelButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickTSkelLamp.Color = setColor;
            end
        end

        % Button pushed function: PickASkelButton
        function PickASkelButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickASkelLamp.Color = setColor;
            end
        end

        % Button pushed function: PickBSkelButton
        function PickBSkelButtonPushed(app, event)
            setColor = uisetcolor();
            if ~isequal(setColor, 0) % If a color was selected
                app.PickBSkelLamp.Color = setColor;
            end
        end

        % Value changed function: totalSkelHullInclude
        function totalSkelHullIncludeValueChanged(app, event)
            if app.totalSkelHullInclude.Value
                app.PickTSkelButton.Visible = 'on';
                app.PickTSkelLamp.Visible = 'on';
            else
                app.PickTSkelButton.Visible = 'off';
                app.PickTSkelLamp.Visible = 'off';
            end
            
        end

        % Value changed function: aboveSkelHullInclude
        function aboveSkelHullIncludeValueChanged(app, event)
            if app.aboveSkelHullInclude.Value
                app.PickASkelButton.Visible = 'on';
                app.PickASkelLamp.Visible = 'on';
            else
                app.PickASkelButton.Visible = 'off';
                app.PickASkelLamp.Visible = 'off';
            end           
        end

        % Value changed function: belowSkelHullInclude
        function belowSkelHullIncludeValueChanged(app, event)
            if app.belowSkelHullInclude.Value
                app.PickBSkelButton.Visible = 'on';
                app.PickBSkelLamp.Visible = 'on';
            else
                app.PickBSkelButton.Visible = 'off';
                app.PickBSkelLamp.Visible = 'off';
            end
            
        end

        % Value changed function: CropModeButton
        function CropModeButtonValueChanged(app, event)
            if app.CropModeButton.Value == 1 % 1 = Poly crop, 0 = simple crop
                app.CropModeButton.Text = 'Polygonal cropping';
            else
                app.CropModeButton.Text = 'Simple cropping';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArborTools = uifigure('Visible', 'off');
            app.ArborTools.Name = 'Arbor Tools';
            app.ArborTools.CloseRequestFcn = createCallbackFcn(app, @ArborToolsCloseRequest, true);
            app.ArborTools.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ArborTools);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModeTabGroup = uitabgroup(app.LeftPanel);
            app.ExtractionmodeTab = uitab(app.ModeTabGroup);
            app.ExtractionmodeTab.Title = 'Extraction mode';
            app.SelectfolderButton = uibutton(app.ExtractionmodeTab, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.SelectfolderLamp = uilamp(app.ExtractionmodeTab);
            app.XYsizemicronsEditFieldLabel = uilabel(app.ExtractionmodeTab);
            app.XYsizemicronsEditFieldLabel.Text = 'XY size microns';
            app.XYsizemicronsEditField = uieditfield(app.ExtractionmodeTab, 'numeric');
            app.XYsizemicronsEditField.Limits = [0 Inf];
            app.XYsizemicronsEditField.ValueChangedFcn = createCallbackFcn(app, @XYsizemicronsEditFieldValueChanged, true);
            app.XYsizemicronsEditField.Tooltip = {'Assumes square slices and one micron per slice/Z level.'};
            app.XYsizemicronsEditField.Value = 80;
            app.SelectfileDropDownLabel = uilabel(app.ExtractionmodeTab);
            app.SelectfileDropDownLabel.Text = 'Select file';
            app.SelectfileDropDown = uidropdown(app.ExtractionmodeTab);
            app.SelectfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectfileDropDownValueChanged, true);
            app.CropstackButton = uibutton(app.ExtractionmodeTab, 'push');
            app.CropstackButton.ButtonPushedFcn = createCallbackFcn(app, @CropstackButtonPushed, true);
            app.CropstackButton.Text = 'Crop stack';
            app.SelectcropwindowLamp = uilamp(app.ExtractionmodeTab);
            app.VolumeremovalPanel = uipanel(app.ExtractionmodeTab);
            app.VolumeremovalPanel.Title = 'Volume removal';
            app.UITable = uitable(app.VolumeremovalPanel);
            app.UITable.ColumnName = {'ID'; 'Volume'; 'Keep?'; 'Color'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [false false true false];
            app.UITable.Tooltip = {'Volume expressed in voxels.'};
            app.RemoveVolsButton = uibutton(app.VolumeremovalPanel, 'push');
            app.RemoveVolsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveVolsButtonPushed, true);
            app.RemoveVolsButton.Text = 'Remove all but selected volume(s)';
            app.WritedatafileButton = uibutton(app.ExtractionmodeTab, 'push');
            app.WritedatafileButton.ButtonPushedFcn = createCallbackFcn(app, @WritedatafileButtonPushed, true);
            app.WritedatafileButton.Tooltip = {'Takes segmentation variables from controls, so don''t change the control values after final segmentation before writing data.'};
            app.WritedatafileButton.Text = 'Write data file';
            app.segSkelTabGroup = uitabgroup(app.ExtractionmodeTab);
            app.segCtrlsTab = uitab(app.segSkelTabGroup);
            app.segCtrlsTab.Title = 'Segmentation';
            app.segCtrlsPanel = uipanel(app.segCtrlsTab);
            app.aandbSpinnerLabel = uilabel(app.segCtrlsPanel);
            app.aandbSpinnerLabel.Text = 'a and b';
            app.aandbSpinner = uispinner(app.segCtrlsPanel);
            app.aandbSpinner.Limits = [1 Inf];
            app.aandbSpinner.RoundFractionalValues = 'on';
            app.aandbSpinner.Editable = 'off';
            app.aandbSpinner.Tooltip = {'Pixels'};
            app.aandbSpinner.Value = 5;
            app.cSpinnerLabel = uilabel(app.segCtrlsPanel);
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.segCtrlsPanel);
            app.cSpinner.Limits = [1 Inf];
            app.cSpinner.RoundFractionalValues = 'on';
            app.cSpinner.Editable = 'off';
            app.cSpinner.Tooltip = {'Pixels'};
            app.cSpinner.Value = 3;
            app.DeltaEpsiEditField = uieditfield(app.segCtrlsPanel, 'numeric');
            app.DeltaEpsiEditField.Tooltip = {'Delta and Epsilon'};
            app.DandELabel = uilabel(app.segCtrlsPanel);
            app.DandELabel.Text = 'DandE';
            app.GEditField = uieditfield(app.segCtrlsPanel, 'numeric');
            app.GEditField.Tooltip = {'Gamma'};
            app.GEditFieldLabel = uilabel(app.segCtrlsPanel);
            app.GEditFieldLabel.Text = 'G';
            app.ApplyPFilterButton = uibutton(app.segCtrlsPanel, 'push');
            app.ApplyPFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPFilterButtonPushed, true);
            app.ApplyPFilterButton.Tooltip = {'Also acts as reset if you want to start over with volume removal.'};
            app.ApplyPFilterButton.Text = 'Apply Segmentation';
            app.ClosingelementsizeSpinnerLabel = uilabel(app.segCtrlsPanel);
            app.ClosingelementsizeSpinnerLabel.Text = 'Closing element size';
            app.ClosingelementsizeSpinner = uispinner(app.segCtrlsPanel);
            app.ClosingelementsizeSpinner.Limits = [0 Inf];
            app.ClosingelementsizeSpinner.RoundFractionalValues = 'on';
            app.ClosingelementsizeSpinner.Editable = 'off';
            app.ClosingelementsizeSpinner.Tooltip = {'Pixels. Used to try and close any gaps in the volume. When used, minimum is two.'; ''; 'As small as possible while still connecting what needs to be connected.'};
            app.PrescaleSpinnerLabel = uilabel(app.segCtrlsPanel);
            app.PrescaleSpinnerLabel.Text = 'Prescale';
            app.PrescaleSpinner = uispinner(app.segCtrlsPanel);
            app.PrescaleSpinner.Step = 2;
            app.PrescaleSpinner.Limits = [0 6];
            app.PrescaleSpinner.Editable = 'off';
            app.PrescaleSpinner.Tooltip = {'Resizes the volume before/after applying P filter. Can improve results, but can also lose information. Scale = 0 is no scaling.'};
            app.SkeletonizationTab = uitab(app.segSkelTabGroup);
            app.SkeletonizationTab.Title = 'Skeletonization';
            app.SmoothingSizeSpinnerLabel = uilabel(app.SkeletonizationTab);
            app.SmoothingSizeSpinnerLabel.Text = 'Smoothing Size';
            app.SmoothingSizeSpinner = uispinner(app.SkeletonizationTab);
            app.SmoothingSizeSpinner.Step = 2;
            app.SmoothingSizeSpinner.Limits = [1 Inf];
            app.SmoothingSizeSpinner.RoundFractionalValues = 'on';
            app.SmoothingSizeSpinner.Tooltip = {'Pixel. Size of box filter used to smooth volume before skeletonizing.'};
            app.SmoothingSizeSpinner.Value = 3;
            app.SkeletonizeButton = uibutton(app.SkeletonizationTab, 'push');
            app.SkeletonizeButton.ButtonPushedFcn = createCallbackFcn(app, @SkeletonizeButtonPushed, true);
            app.SkeletonizeButton.Tooltip = {'Total arbor length calculated in batch analysis.'};
            app.SkeletonizeButton.Text = 'Skeletonize';
            app.MinimumBranchLengthEditFieldLabel = uilabel(app.SkeletonizationTab);
            app.MinimumBranchLengthEditFieldLabel.Text = 'Minimum Branch Length';
            app.MinimumBranchLengthEditField = uieditfield(app.SkeletonizationTab, 'numeric');
            app.MinimumBranchLengthEditField.Limits = [0 Inf];
            app.MinimumBranchLengthEditField.RoundFractionalValues = 'on';
            app.MinimumBranchLengthEditField.Tooltip = {'Voxels. Uses volume that is not scaled in Z axis, so visually confirm skeleton. Scale accurate lengths calculated in batch analysis.'};
            app.BranchcandidatesEditFieldLabel = uilabel(app.SkeletonizationTab);
            app.BranchcandidatesEditFieldLabel.Text = '# Branch candidates';
            app.BranchcandidatesEditField = uieditfield(app.SkeletonizationTab, 'numeric');
            app.BranchcandidatesEditField.Limits = [0 Inf];
            app.BranchcandidatesEditField.Editable = 'off';
            app.EndpointcandidatesEditFieldLabel = uilabel(app.SkeletonizationTab);
            app.EndpointcandidatesEditFieldLabel.Text = '# Endpoint candidates';
            app.EndpointcandidatesEditField = uieditfield(app.SkeletonizationTab, 'numeric');
            app.EndpointcandidatesEditField.Limits = [0 Inf];
            app.EndpointcandidatesEditField.Editable = 'off';
            app.ErodevolumeCheckBox = uicheckbox(app.SkeletonizationTab);
            app.ErodevolumeCheckBox.Tooltip = {'Adds an erosion step that can dramatically improve endpoint detection and remove erroneous loops.'};
            app.ErodevolumeCheckBox.Text = 'Erode volume?';
            app.ErodevolumeCheckBox.Value = true;
            app.CropModeButton = uibutton(app.ExtractionmodeTab, 'state');
            app.CropModeButton.ValueChangedFcn = createCallbackFcn(app, @CropModeButtonValueChanged, true);
            app.CropModeButton.Tooltip = {'Polygonal crop = Draw three polygons point by point on three 2D projections to define a 3D polygonal mask. Everything outside mask set to 0. Bounding box of mask used to crop. Crops through entire stack.'; ''; 'Simple = Draw a single rectangle on top down projection. Crops through entire stack.'};
            app.CropModeButton.Text = 'Polygonal cropping';
            app.CropModeButton.Value = true;
            app.BatchanalysisTab = uitab(app.ModeTabGroup);
            app.BatchanalysisTab.Title = 'Batch analysis';
            app.SelectmatFolderButton = uibutton(app.BatchanalysisTab, 'push');
            app.SelectmatFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmatFolderButtonPushed, true);
            app.SelectmatFolderButton.Text = 'Select .mat folder';
            app.SelectmatFolderLamp = uilamp(app.BatchanalysisTab);
            app.ProcessingcontrolsPanel = uipanel(app.BatchanalysisTab);
            app.ProcessingcontrolsPanel.Title = 'Processing controls';
            app.ZlevelcutoffEditFieldLabel = uilabel(app.ProcessingcontrolsPanel);
            app.ZlevelcutoffEditFieldLabel.Text = 'Z level cutoff';
            app.ZlevelcutoffEditField = uieditfield(app.ProcessingcontrolsPanel, 'numeric');
            app.ZlevelcutoffEditField.Limits = [0 Inf];
            app.ZlevelcutoffEditField.RoundFractionalValues = 'on';
            app.ZlevelcutoffEditField.Tooltip = {'Everything this many Z levels below the taste bud are ignored during calculations.'};
            app.ZlevelcutoffEditField.Value = 20;
            app.ZScaleEditFieldLabel = uilabel(app.ProcessingcontrolsPanel);
            app.ZScaleEditFieldLabel.Text = 'Z Scale';
            app.ZScaleEditField = uieditfield(app.ProcessingcontrolsPanel, 'numeric');
            app.ZScaleEditField.Limits = [0 Inf];
            app.ZScaleEditField.Tooltip = {'Z Scale to apply to any images/data generated to create XYZ scale of 1-1-1 (allows use of XY scale factor).'};
            app.ZScaleEditField.Value = 6.4;
            app.TBZxlsxButton = uibutton(app.BatchanalysisTab, 'push');
            app.TBZxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @TBZxlsxButtonPushed, true);
            app.TBZxlsxButton.Text = 'Select .xlsx with taste bud Z levels';
            app.TBZxlsxLamp = uilamp(app.BatchanalysisTab);
            app.StartProcessingButton = uibutton(app.BatchanalysisTab, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Process and write data';
            app.ProjectionControlsPanel = uipanel(app.BatchanalysisTab);
            app.ProjectionControlsPanel.Title = 'Projection controls';
            app.AboveHullInclude = uicheckbox(app.ProjectionControlsPanel);
            app.AboveHullInclude.ValueChangedFcn = createCallbackFcn(app, @AboveHullIncludeValueChanged, true);
            app.AboveHullInclude.Tooltip = {'Outline of convex hull of what is above the taste bud.'};
            app.AboveHullInclude.Text = 'Draw above convex hull outline?';
            app.AboveHullInclude.Value = true;
            app.BelowHullInclude = uicheckbox(app.ProjectionControlsPanel);
            app.BelowHullInclude.ValueChangedFcn = createCallbackFcn(app, @BelowHullIncludeValueChanged, true);
            app.BelowHullInclude.Tooltip = {'Outline of convex hull of what is below the taste bud.'};
            app.BelowHullInclude.Text = 'Draw below convex hull outline?';
            app.BelowHullInclude.Value = true;
            app.AllHullInclude = uicheckbox(app.ProjectionControlsPanel);
            app.AllHullInclude.ValueChangedFcn = createCallbackFcn(app, @AllHullIncludeValueChanged, true);
            app.AllHullInclude.Tooltip = {'Outline of convex hull of the entire arbor.'};
            app.AllHullInclude.Text = 'Draw total convex hull outline?';
            app.AllHullInclude.Value = true;
            app.PickAboveColorButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickAboveColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickAboveColorButtonPushed, true);
            app.PickAboveColorButton.Text = 'Pick above color';
            app.PickAboveColorLamp = uilamp(app.ProjectionControlsPanel);
            app.PickBelowColorLamp = uilamp(app.ProjectionControlsPanel);
            app.PickBelowColorButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickBelowColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickBelowColorButtonPushed, true);
            app.PickBelowColorButton.Text = 'Pick below color';
            app.PickTotalColorLamp = uilamp(app.ProjectionControlsPanel);
            app.PickTotalColorButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickTotalColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickTotalColorButtonPushed, true);
            app.PickTotalColorButton.Text = 'Pick total color';
            app.tbToAboveInclude = uicheckbox(app.ProjectionControlsPanel);
            app.tbToAboveInclude.ValueChangedFcn = createCallbackFcn(app, @tbToAboveIncludeValueChanged, true);
            app.tbToAboveInclude.Tooltip = {'Line from TB centroid to above volume centroid (not to centroid of convex hull).'};
            app.tbToAboveInclude.Text = 'Draw line from TB centroid to above volume centroid?';
            app.tbToAboveInclude.Value = true;
            app.PickAboveLineLamp = uilamp(app.ProjectionControlsPanel);
            app.PickAboveLineButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickAboveLineButton.ButtonPushedFcn = createCallbackFcn(app, @PickAboveLineButtonPushed, true);
            app.PickAboveLineButton.Text = 'Pick above line color';
            app.tbToBelowInclude = uicheckbox(app.ProjectionControlsPanel);
            app.tbToBelowInclude.ValueChangedFcn = createCallbackFcn(app, @tbToBelowIncludeValueChanged, true);
            app.tbToBelowInclude.Tooltip = {'Line from TB centroid to below volume centroid (not to centroid of convex hull).'};
            app.tbToBelowInclude.Text = 'Draw line from TB centroid to below volume centroid?';
            app.tbToBelowInclude.Value = true;
            app.PickBelowLineButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickBelowLineButton.ButtonPushedFcn = createCallbackFcn(app, @PickBelowLineButtonPushed, true);
            app.PickBelowLineButton.Text = 'Pick below line color';
            app.PickBelowLineLamp = uilamp(app.ProjectionControlsPanel);
            app.DrawskeletonCheckBox = uicheckbox(app.ProjectionControlsPanel);
            app.DrawskeletonCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawskeletonCheckBoxValueChanged, true);
            app.DrawskeletonCheckBox.Text = 'Draw skeleton?';
            app.DrawskeletonCheckBox.Value = true;
            app.PickSkeletonButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickSkeletonButton.ButtonPushedFcn = createCallbackFcn(app, @PickSkeletonButtonPushed, true);
            app.PickSkeletonButton.Text = 'Pick skeleton color';
            app.PickSkeletonLamp = uilamp(app.ProjectionControlsPanel);
            app.aboveSkelHullInclude = uicheckbox(app.ProjectionControlsPanel);
            app.aboveSkelHullInclude.ValueChangedFcn = createCallbackFcn(app, @aboveSkelHullIncludeValueChanged, true);
            app.aboveSkelHullInclude.Text = 'Draw above skel conv hull?';
            app.aboveSkelHullInclude.Value = true;
            app.PickASkelButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickASkelButton.ButtonPushedFcn = createCallbackFcn(app, @PickASkelButtonPushed, true);
            app.PickASkelButton.Text = 'Pick above skel color';
            app.PickASkelLamp = uilamp(app.ProjectionControlsPanel);
            app.totalSkelHullInclude = uicheckbox(app.ProjectionControlsPanel);
            app.totalSkelHullInclude.ValueChangedFcn = createCallbackFcn(app, @totalSkelHullIncludeValueChanged, true);
            app.totalSkelHullInclude.Text = 'Draw total skel conv hull?';
            app.totalSkelHullInclude.Value = true;
            app.PickTSkelButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickTSkelButton.ButtonPushedFcn = createCallbackFcn(app, @PickTSkelButtonPushed, true);
            app.PickTSkelButton.Text = 'Pick total skel color';
            app.PickTSkelLamp = uilamp(app.ProjectionControlsPanel);
            app.belowSkelHullInclude = uicheckbox(app.ProjectionControlsPanel);
            app.belowSkelHullInclude.ValueChangedFcn = createCallbackFcn(app, @belowSkelHullIncludeValueChanged, true);
            app.belowSkelHullInclude.Text = 'Draw below skel conv hull?';
            app.belowSkelHullInclude.Value = true;
            app.PickBSkelButton = uibutton(app.ProjectionControlsPanel, 'push');
            app.PickBSkelButton.ButtonPushedFcn = createCallbackFcn(app, @PickBSkelButtonPushed, true);
            app.PickBSkelButton.Text = 'Pick below skel color';
            app.PickBSkelLamp = uilamp(app.ProjectionControlsPanel);
            app.CreateProjectionsCheckbox = uicheckbox(app.BatchanalysisTab);
            app.CreateProjectionsCheckbox.ValueChangedFcn = createCallbackFcn(app, @CreateProjectionsCheckboxValueChanged, true);
            app.CreateProjectionsCheckbox.Text = 'Create projection images?';
            app.CreateProjectionsCheckbox.Value = true;
            app.SelectOutputFolderButton = uibutton(app.BatchanalysisTab, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select output folder';
            app.SelectOutputFolderLamp = uilamp(app.BatchanalysisTab);
            app.CreateSWCfilesCheckBox = uicheckbox(app.BatchanalysisTab);
            app.CreateSWCfilesCheckBox.Tooltip = {'Create a SWC for each tree (total, above, and below) for each arbor.'};
            app.CreateSWCfilesCheckBox.Text = 'Create SWC files?';
            app.CreateSWCfilesCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.ArborTools.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arborTools

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArborTools)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArborTools)
        end
    end
end